classdef CellMAP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CellMAPUIFigure                 matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        NewsessionMenu                  matlab.ui.container.Menu
        LoadsessionMenu                 matlab.ui.container.Menu
        ExportMenu                      matlab.ui.container.Menu
        FiguresMenu                     matlab.ui.container.Menu
        CurrentmaphistogramMenu         matlab.ui.container.Menu
        CurrentmapMenu                  matlab.ui.container.Menu
        CurrenthistogramMenu            matlab.ui.container.Menu
        ExportSessionMenu               matlab.ui.container.Menu
        DataMenu                        matlab.ui.container.Menu
        AddonMenu                       matlab.ui.container.Menu
        PipelineMenu                    matlab.ui.container.Menu
        RecordMenu                      matlab.ui.container.Menu
        LoadMenu                        matlab.ui.container.Menu
        LoadforcecurvesMenu             matlab.ui.container.Menu
        BatchprocessingofcontactpointMenu  matlab.ui.container.Menu
        FittingfromcontactpointMenu     matlab.ui.container.Menu
        PlotYfXMenu                     matlab.ui.container.Menu
        CurvatureMenu                   matlab.ui.container.Menu
        GeometryThresholdcurveMenu      matlab.ui.container.Menu
        MenuHelp                        matlab.ui.container.Menu
        DescriptionMenu                 matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        Image2                          matlab.ui.control.Image
        Copyright                       matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        ROILabel                        matlab.ui.control.Label
        GridLayout2                     matlab.ui.container.GridLayout
        N_cellEditField                 matlab.ui.control.NumericEditField
        N_cellEditFieldLabel            matlab.ui.control.Label
        ParametersPanel                 matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        CellnSpinnerLabel               matlab.ui.control.Label
        CellnSpinner                    matlab.ui.control.Spinner
        DeleteButtonFilter              matlab.ui.control.Button
        DeleteButtoVar                  matlab.ui.control.Button
        DeleteButtonCell                matlab.ui.control.Button
        DropDownFilter                  matlab.ui.control.DropDown
        DropDownVariable                matlab.ui.control.DropDown
        ProcessLabel                    matlab.ui.control.Label
        DropDownCell                    matlab.ui.control.DropDown
        TypeofdataLabel                 matlab.ui.control.Label
        UnitLabel                       matlab.ui.control.Label
        UnitEditField                   matlab.ui.control.EditField
        ConvertButton                   matlab.ui.control.Button
        PanelHistogram                  matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        DistributionFitterfromMatlabButton  matlab.ui.control.Button
        ImageMouse                      matlab.ui.control.Image
        SliderHisto                     matlab.ui.control.Slider
        DropDownNormalization           matlab.ui.control.DropDown
        NormalizationDropDownLabel      matlab.ui.control.Label
        EditFieldBinWidth               matlab.ui.control.NumericEditField
        BinwidthLabel                   matlab.ui.control.Label
        unitLabel_8                     matlab.ui.control.Label
        FittingparametersaredisplayedinthecommandwindowLabel  matlab.ui.control.Label
        HistogramFitterButton           matlab.ui.control.Button
        CheckBoxHisto                   matlab.ui.control.CheckBox
        FixedCheckBoxHisto              matlab.ui.control.CheckBox
        EditFieldHistoLow               matlab.ui.control.NumericEditField
        EditFieldHistoHigh              matlab.ui.control.NumericEditField
        UIAxesHisto                     matlab.ui.control.UIAxes
        PanelHisto                      matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        QuantileDropDown                matlab.ui.control.DropDown
        QuantileDropDownLabel           matlab.ui.control.Label
        MaximumEditFieldLabel           matlab.ui.control.Label
        MaximumEditField                matlab.ui.control.EditField
        MedianEditFieldLabel            matlab.ui.control.Label
        QuantileEditField               matlab.ui.control.EditField
        DatapointsEditField             matlab.ui.control.EditField
        DatapointsEditFieldLabel        matlab.ui.control.Label
        MinimumEditFieldLabel           matlab.ui.control.Label
        MinimumEditField                matlab.ui.control.EditField
        GeometricstandarddeviationEditFieldLabel  matlab.ui.control.Label
        GeometricstandarddeviationEditField  matlab.ui.control.EditField
        StandarderrorofthemeanEditFieldLabel  matlab.ui.control.Label
        StandarderrorofthemeanEditField  matlab.ui.control.EditField
        StandarddeviationEditFieldLabel  matlab.ui.control.Label
        StandarddeviationEditField      matlab.ui.control.EditField
        ArithmeticmeanEditFieldLabel    matlab.ui.control.Label
        ArithmeticmeanEditField         matlab.ui.control.EditField
        GeometricmeanEditFieldLabel     matlab.ui.control.Label
        GeometricmeanEditField          matlab.ui.control.EditField
        unitLabel                       matlab.ui.control.Label
        PanelGeometry                   matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        VolumeEditFieldLabel            matlab.ui.control.Label
        VolumeEditField                 matlab.ui.control.EditField
        ProjectedsurfaceEditFieldLabel  matlab.ui.control.Label
        ProjectedsurfaceEditField       matlab.ui.control.EditField
        TopareaEditFieldLabel           matlab.ui.control.Label
        TopareaEditField                matlab.ui.control.EditField
        PixelsizeEditFieldLabel         matlab.ui.control.Label
        PixelsizeEditField              matlab.ui.control.NumericEditField
        mLabel                          matlab.ui.control.Label
        mLabelProjectedSurface          matlab.ui.control.Label
        mLabelVolume                    matlab.ui.control.Label
        mLabelTopArea                   matlab.ui.control.Label
        IfheightinmLabel                matlab.ui.control.Label
        OperationsPanel                 matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        FlatteningTab                   matlab.ui.container.Tab
        ButtonROILeveling               matlab.ui.control.Button
        ThelevelingisdonefromtheimageshownhereLabel  matlab.ui.control.Label
        ButtonDoitLeveling              matlab.ui.control.Button
        ThresholdingTab                 matlab.ui.container.Tab
        EditFieldLowThres               matlab.ui.control.NumericEditField
        LowerLabel                      matlab.ui.control.Label
        unitLabel_10                    matlab.ui.control.Label
        TheflatteningisdonefromtheimageshownhereLabel_2  matlab.ui.control.Label
        ButtonDoitThres                 matlab.ui.control.Button
        EditFieldUpThres                matlab.ui.control.NumericEditField
        UpperEditFieldLabel             matlab.ui.control.Label
        CleaningTab                     matlab.ui.container.Tab
        TheflatteningisdonefromtheimageshownhereLabel_3  matlab.ui.control.Label
        ButtonDoitCleaning              matlab.ui.control.Button
        EditFieldNumPix                 matlab.ui.control.NumericEditField
        NumberofpixelsEditFieldLabel    matlab.ui.control.Label
        PeaksmoothingTab                matlab.ui.container.Tab
        unitLabel_11                    matlab.ui.control.Label
        EditFieldGradientstep           matlab.ui.control.NumericEditField
        GradientstepLabel               matlab.ui.control.Label
        ButtonDoitPeakremoving          matlab.ui.control.Button
        TheflatteningisdonefromtheimageshownhereLabel_4  matlab.ui.control.Label
        HoleremovingTab                 matlab.ui.container.Tab
        TheflatteningisdonefromtheimageshownhereLabel_7  matlab.ui.control.Label
        ButtonDoitHoleremoving          matlab.ui.control.Button
        CenteringTab                    matlab.ui.container.Tab
        TheflatteningisdonefromtheimageshownhereLabel_5  matlab.ui.control.Label
        ButtonDoitCentering             matlab.ui.control.Button
        MakeMontageTab                  matlab.ui.container.Tab
        TheflatteningisdonefromtheimageshownhereLabel_8  matlab.ui.control.Label
        DoitButton                      matlab.ui.control.Button
        TopographyTab                   matlab.ui.container.Tab
        ButtonDoitTopography            matlab.ui.control.Button
        TheflatteningisdonefromtheimageshownhereLabel_6  matlab.ui.control.Label
        DropDownVariable_2              matlab.ui.control.DropDown
        AveragingTab                    matlab.ui.container.Tab
        AveragecellsintooneLabel        matlab.ui.control.Label
        ButtonDoitAveraging             matlab.ui.control.Button
        DataROI                         matlab.ui.container.Tab
        ButtonROIManager                matlab.ui.control.Button
        ButtonROILabel                  matlab.ui.control.Label
        MappingPanel                    matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        ForcecurveCheckBox              matlab.ui.control.CheckBox
        ImageKeyboard                   matlab.ui.control.Image
        SliderCursorY                   matlab.ui.control.Slider
        EditFieldCursor                 matlab.ui.control.EditField
        unitLabel_12                    matlab.ui.control.Label
        EditFieldMapLow                 matlab.ui.control.NumericEditField
        unitLabel_9                     matlab.ui.control.Label
        EditFieldMapHigh                matlab.ui.control.NumericEditField
        FixedCheckBoxMap                matlab.ui.control.CheckBox
        SliderCursorX                   matlab.ui.control.Slider
        EditFieldCoordX                 matlab.ui.control.NumericEditField
        EditFieldCoordY                 matlab.ui.control.NumericEditField
        DatacursorCheckBox              matlab.ui.control.CheckBox
        UIAxesMap                       matlab.ui.control.UIAxes
    end


    properties (Access = public)
        Var
        ContactPointName = [];
        ForceCurves
        displayMap
        displayHisto
        VarDescription
        savepath
        Pipeline
        Fitting
        NewContactPoint
        calledPlotYfXApp
        mask = [];
        ROIList = {}        % cell array of structs: {roiObj, name, textHandle}
        ROIcount = 0
        ROIManagerFig      % handle to manager uifigure
        SavedROIs = struct([])   % in-memory saved ROI structs: name, mask, position, type, time
        ROIListeners = {}   % cell array of listener handles
    end

    properties (Access = private)
        calledFittingApp; % Description
        calledForceApp
        calledContactPointApp
        calledHeightApp
    end

    methods (Access = private)

        function [FilterNames,VarNames,CellNames] = getItems(app)
            FilterNames = app.DropDownFilter.Items;
            VarNames = app.DropDownVariable.Items;
            CellNames = app.DropDownCell.Items;
        end

        function setValues(app,field,FilterName,VarName,CellName)
            if nargin == 2
                FilterName = app.DropDownFilter.Value;
                VarName = app.DropDownVariable.Value;
                CellName = app.DropDownCell.Value;
                app.Var.(FilterName).(VarName).(CellName) = field;
            elseif nargin == 3
                if iscell(FilterName)
                    FilterName = cell2mat(FilterName);
                end
                app.Var.(FilterName) = field;
            elseif nargin == 4
                if iscell(FilterName)
                    FilterName = cell2mat(FilterName);
                end
                if iscell(VarName)
                    VarName = cell2mat(VarName);
                end
                app.Var.(FilterName).(VarName) = field;
            elseif nargin == 5
                if iscell(FilterName)
                    FilterName = cell2mat(FilterName);
                end
                if iscell(VarName)
                    VarName = cell2mat(VarName);
                end
                if iscell(CellName)
                    CellName = cell2mat(CellName);
                end
                app.Var.(FilterName).(VarName).(CellName) = field;
            end
        end

        function newname = duplicateFilter(app,newFilterName,desc)
            [~,FilterName] = app.getValues;
            FilterNames = app.getItems;
            newname = strcat(FilterName,newFilterName);
            newname = matlab.lang.makeUniqueStrings(newname,FilterNames);
            app.Var.(newname) = app.Var.(FilterName);
            app.DropDownFilter.Items{end+1} = newname;
            app.DropDownFilter.Value = app.DropDownFilter.Items{end};
            app.VarDescription.(app.DropDownFilter.Value) = desc;
        end

        function newVarName = duplicateVar(app,newVarName)
            [~,~,VarName] = app.getValues;
            [FilterNames,VarNames] = app.getItems;
            newVarName = matlab.lang.makeUniqueStrings(newVarName,VarNames);
            for FilterName = FilterNames
                app.Var.(FilterName{:}).(newVarName) = app.Var.(FilterName{:}).(VarName);
            end
            app.DropDownVariable.Items{end+1} = newVarName;
            app.DropDownVariable_2.Items{end+1} = newVarName;
        end

        function Calculate(app,mask)
            %% Update Surface and Histogram values here
            [~,FilterName] = app.getValues;
            [~,VarNames,CellNames] = app.getItems;
            for VarName = VarNames
                for CellName = CellNames
                    field = app.getValues(FilterName,VarName,CellName);
                    data = field.data;
                    field.pixel = app.PixelsizeEditField.Value;
                    if nargin < 2               
                        field = calcStat(field,data);
                    else
                        data_mask = field.data.*mask;
                        data_mask(data_mask==0) = NaN;
                        field = calcStat(field,data_mask);
                    end
                    app.setValues(field,FilterName,VarName,CellName)
                end
                subfield = app.getValues(FilterName,VarName);
                subfield = combineStat(subfield,CellNames);
                app.setValues(subfield,FilterName,VarName)
            end
        end

        %%%%%% UPTADE GRAPHS

        function UIAxesMapUpdate(app,map)
            if nargin < 2
                map = app.UIAxesMap;
                disableDefaultInteractivity(map)
                figure(app.CellMAPUIFigure)
            end
            [field,~,~,CellName] = app.getValues;
            data = field.data;
            ncol = size(data,2);
            nrow = size(data,1);
            % Display map topography
            hold(map,'off');
            h = image(data,'CDataMapping','scaled','XData',[1 ncol],'YData',[1 nrow],'Parent',map);
            hold(map,'on');
            % Set NaN values to transparent
            set(h,'alphadata',~isnan(data))
            % Set the color scale
            if app.FixedCheckBoxMap.Value
                m = app.EditFieldMapLow.Value;
                M = app.EditFieldMapHigh.Value;
            else
                m = min(data(:));
                M = max(data(:));
                app.EditFieldMapLow.Value = m;
                app.EditFieldMapHigh.Value = M;
            end
            map.CLim = [m M];
            % Set the x-y scales
            map.XLim = [.5 ncol+.5];
            map.YLim = [.5 nrow+.5];
            map.DataAspectRatio = [1 1 1];
            if app.DatacursorCheckBox.Value
                app.SliderCursorX.Limits(2) = ncol;
                app.SliderCursorY.Limits(2) = nrow;
                xSlider = floor(app.SliderCursorX.Value);
                ySlider = floor(app.SliderCursorY.Value);
                row = nrow - ySlider + 1;
                col = xSlider;
                plot(map,col,row,'+w')
                app.EditFieldCursor.Value = num2str(data(row,col),'%.3g');
                app.EditFieldCoordX.Value = xSlider;
                app.EditFieldCoordY.Value = ySlider;
                if ~isempty(app.ForceCurves)
                    app.ForcecurveCheckBox.Visible = 1;
                    if app.ForcecurveCheckBox.Value
                        if isempty(app.calledForceApp)
                            app.calledForceApp = ForceCurveApp(app,row,col);
                        else
                            app.calledForceApp.updateaxes(row,col);
                        end
                    else
                        if ~isempty(app.calledForceApp)
                            app.calledForceApp.deleteapp
                            app.calledForceApp = [];
                        end
                    end
                end
                if isempty(app.calledHeightApp)
                    app.calledHeightApp = HeightProfile(app,row,col);
                else
                    app.calledHeightApp.updateaxes(row,col);
                end
            else
                app.ForcecurveCheckBox.Visible = 0;
                if ~isempty(app.calledForceApp)
                    app.calledForceApp.deleteapp
                end
                app.calledForceApp = [];
                if ~isempty(app.calledHeightApp)
                    app.calledHeightApp.deleteapp
                end
                app.calledHeightApp = [];
            end
            if ~isempty(app.calledPlotYfXApp)
                app.calledPlotYfXApp.updateaxes;
            end
            if app.CheckBoxHisto.Value
                histHandle = app.UIAxesHisto.Children(2);
                n = app.SliderHisto.Value;
                cond = data >= histHandle.BinEdges(n) & data < histHandle.BinEdges(n+1);
                [xHisto,yHisto]=find(cond==true);
                plot(map,yHisto,xHisto,'sr')
            end
            title(map,CellName,'interpreter', 'none','Color','white')
            colormap(map,copper)
            colorbar(map,'Color','white');
            app.PixelsizeEditField.Value = field.pixel;
            app.ProjectedsurfaceEditField.Value = num2str(field.SurfaceProjected,'%.3g');
            app.TopareaEditField.Value = num2str(field.TopArea,'%.3g');
            app.VolumeEditField.Value = num2str(field.Volume,'%.3g');
            app.DatapointsEditField.Value = num2str(field.Datapoints,'%.3g');
        end

        function UIAxesHistoUpdate(app,histo,mask)
            field = app.getValues;
            if nargin < 2
                histo = app.UIAxesHisto;
                disableDefaultInteractivity(histo)
                figure(app.CellMAPUIFigure)
                data = field.data;
            elseif nargin == 3
                disableDefaultInteractivity(histo)
                figure(app.CellMAPUIFigure)
                data_mask = field.data.*mask;
                data_mask(data_mask==0) = NaN;
                data = data_mask;
            end
            f = find(~isnan(data),1);
            if ~isempty(f)
                if app.FixedCheckBoxHisto.Value
                    m = app.EditFieldHistoLow.Value;
                    M = app.EditFieldHistoHigh.Value;
                    BinWidth = app.EditFieldBinWidth.Value;
                    if BinWidth > (M-m) || (M-m)/BinWidth > 1e4
                        BinWidth = (M-m)/10;
                        app.EditFieldBinWidth.Value = BinWidth;
                    end
                else
                    m = min(data(:));
                    M = max(data(:));
                    BinWidth = (M-m)/10;
                    app.EditFieldHistoLow.Value = m;
                    app.EditFieldHistoHigh.Value = M;
                    app.EditFieldBinWidth.Value = BinWidth;
                end
                edges = m:BinWidth:M;
                hold(histo,'off');
                histogram(histo,data(~isnan(data)),edges,'Normalization',...
                    app.DropDownNormalization.Value,'LineWidth',1,'FaceColor',[0.8 0.8 0.8],'EdgeColor','white');
                hold(histo,'on');
                xlim(histo,[m M])
                ylabel(histo,app.DropDownNormalization.Value)
                if app.CheckBoxHisto.Value
                    histHandle = histo.Children;
                    app.SliderHisto.Limits = [1 histHandle.NumBins];
                    n = app.SliderHisto.Value;
                    y = histHandle.Values(n);
                    x = histHandle.BinEdges(n) + histHandle.BinWidth/2;
                    plot(histo,x,y,'or')
                end

                app.MinimumEditField.Value = num2str(field.HistoMin,'%.3g');
                app.MaximumEditField.Value = num2str(field.HistoMax,'%.3g');
                app.GeometricmeanEditField.Value = num2str(field.HistoGeomean,'%.3g');
                app.ArithmeticmeanEditField.Value = num2str(field.HistoMean,'%.3g');
                value = get(app.QuantileDropDown,'Value');
                if isfield(field,'HistoQuantile')
                    app.QuantileEditField.Value = num2str(field.HistoQuantile(value),'%.3g');
                else
                    app.QuantileEditField.Value = num2str(field.HistoMedian,'%.3g');
                end
                app.StandarddeviationEditField.Value = num2str(field.HistoStd,'%.3g');
                app.StandarderrorofthemeanEditField.Value = num2str(field.HistoSem,'%.3g');
                app.GeometricstandarddeviationEditField.Value = num2str(field.HistoGeostd,'%.3g');

                unit = num2str(field.unit);
                app.unitLabel.Text = unit;
                app.unitLabel_8.Text = unit;
                app.unitLabel_9.Text = unit;
                app.unitLabel_10.Text = unit;
                app.unitLabel_11.Text = unit;
                app.unitLabel_12.Text = unit;
                app.UnitEditField.Value = unit;
            else
                cla(app.UIAxesHisto)
            end
        end

        function CalculateAndUpdate(app,mask)
            if nargin < 2
                app.Calculate
                app.UIAxesMapUpdate
                app.UIAxesHistoUpdate
            else
                app.Calculate(mask)
                app.UIAxesMapUpdate
                app.UIAxesHistoUpdate(app.UIAxesHisto,mask)
            end
        end
       
        
        function refreshROIListbox(app)
            if isempty(app.ROIManagerFig) || ~isvalid(app.ROIManagerFig)
                return
            end
            lb = findobj(app.ROIManagerFig, 'Tag', 'ROIlb');
            if isempty(lb), return; end
            if isempty(app.SavedROIs)
                lb.Items = {};
            else
                lb.Items = {app.SavedROIs.name};
            end
        end
        
        function onROIDeleted(app, name)
            % Called when an ROI object is destroyed
            if ~isvalid(app), return; end
            % Do not remove from ROIList, only clear dead graphics handles
            idx = find(cellfun(@(s) strcmp(s.name, name), app.ROIList), 1);
            if ~isempty(idx)
                if isfield(app.ROIList{idx}, 'obj')
                    app.ROIList{idx}.obj = [];
                end
                if isfield(app.ROIList{idx}, 'text') && ~isempty(app.ROIList{idx}.text) ...
                        && isvalid(app.ROIList{idx}.text)
                    delete(app.ROIList{idx}.text);
                    app.ROIList{idx}.text = [];
                end
            end
            % Do NOT remove from SavedROIs here
            % Clean up orphaned listeners
            keep = true(size(app.ROIListeners));
            for k = 1:numel(app.ROIListeners)
                L = app.ROIListeners{k};
                if ~isvalid(L)
                    keep(k) = false;
                end
            end
            app.ROIListeners = app.ROIListeners(keep);
            app.refreshROIListbox();
        end

        function onROISelectionChanged(app, src)
            sel = src.Value;
            if isempty(sel), return; end
            if ischar(sel) || isstring(sel)
                sel = {char(sel)};
            end
            app.showSelectedROIs(sel);
        end

        function showSelectedROIs(app, sel)
            % Delete currently visible ROI graphics
            if ~isempty(app.ROIList)
                for k = 1:numel(app.ROIList)
                    if isfield(app.ROIList{k}, 'obj') && ~isempty(app.ROIList{k}.obj) ...
                            && isvalid(app.ROIList{k}.obj)
                        delete(app.ROIList{k}.obj);
                    end
                    if isfield(app.ROIList{k}, 'text') && ~isempty(app.ROIList{k}.text) ...
                            && isvalid(app.ROIList{k}.text)
                        delete(app.ROIList{k}.text);
                    end
                end
            end
            app.ROIList = {};
            for i = 1:numel(sel)
                name = sel{i};
                sidx = find(strcmp({app.SavedROIs.name}, name), 1);
                if isempty(sidx), continue; end
                pos = app.SavedROIs(sidx).position;
                roi = drawfreehand(app.UIAxesMap, 'Position', pos);
                txtPos = mean(pos,1);
                txt = text(app.UIAxesMap, txtPos(1), txtPos(2), name, ...
                    'Color','g', 'FontSize',8, 'Interpreter','none');
                app.ROIList{end+1} = struct('obj', roi, 'name', name, 'text', txt);
                if isempty(app.ROIListeners)
                    app.ROIListeners = {};
                end
                lh = addlistener(roi, 'ObjectBeingDestroyed', ...
                    @(src,evt) app.onROIDeleted(name));
                app.ROIListeners{end+1} = lh;
            end
        end

        function ButtonUndoItStatPushed(app, event)
            lb = findobj(app.ROIManagerFig, 'Tag', 'ROIlb');
            set(lb,'Value',{})
            app.CalculateAndUpdate
        end

    end

    methods (Access = public)

        function [field,FilterName,VarName,CellName] = getValues(app,FilterName,VarName,CellName)
            if nargin == 1
                FilterName = app.DropDownFilter.Value;
                VarName = app.DropDownVariable.Value;
                CellName = app.DropDownCell.Value;
                field = app.Var.(FilterName).(VarName).(CellName);
            elseif nargin == 2
                if iscell(FilterName)
                    FilterName = cell2mat(FilterName);
                end
                VarName = app.DropDownVariable.Value;
                CellName = app.DropDownCell.Value;
                field = app.Var.(FilterName);
            elseif nargin == 3
                if iscell(FilterName)
                    FilterName = cell2mat(FilterName);
                end
                if iscell(VarName)
                    VarName = cell2mat(VarName);
                end
                CellName = app.DropDownCell.Value;
                field = app.Var.(FilterName).(VarName);
            elseif nargin == 4
                if iscell(FilterName)
                    FilterName = cell2mat(FilterName);
                end
                if iscell(VarName)
                    VarName = cell2mat(VarName);
                end
                if iscell(CellName)
                    CellName = cell2mat(CellName);
                end
                field = app.Var.(FilterName).(VarName).(CellName);
            end
        end

        function [fitted,row,col] = doForceFit(app,myfit,StartPoint,LowerBound,UpperBound,Problem,percent,row,col,CellName)
            if nargin <= 7
                [field,~,~,CellName] = app.getValues;
                data = field.data;
                nrow = size(data,1);
                xSlider = floor(app.SliderCursorX.Value);
                ySlider = floor(app.SliderCursorY.Value);
                row = nrow - ySlider + 1;
                col = xSlider;
            end
            field = app.ForceCurves.(CellName);
            Force = squeeze(field.force(row,col,:));
            Height = squeeze(field.height(row,col,:));
            condHeight = Height < 0 | isnan(Height);
            idx = find(~condHeight);
            numPoint = ceil((1-percent/100)*length(idx));
            idx(1+numPoint:end) = [];
            condHeight(idx) = true;
            if length(Height(~condHeight)) > 2
                [fitted,gof] = fit(Height,Force,myfit,'StartPoint',StartPoint,...
                    'Lower',LowerBound,'Upper',UpperBound,...
                    'problem',Problem,'Exclude',condHeight);
                app.ForceCurves.(CellName).Fitted{row,col} = fitted;
                app.ForceCurves.(CellName).Gof{row,col} = gof;
                app.ForceCurves.(CellName).condHeight{row,col} = condHeight;
            else
                fitted = cell;
                app.ForceCurves.(CellName).Fitted{row,col} = cell;
                app.ForceCurves.(CellName).Gof{row,col} = cell;
                app.ForceCurves.(CellName).condHeight{row,col} = cell;
            end
        end

        function doBatchForceFit(app)
            h = waitbar(0,'Batch processing...');
            n = 0;
            myfit = app.Fitting.myfit;
            variable = probnames(myfit);
            idx = find(ismember(variable, 'v'));
            listCoefficients = coeffnames(myfit);
            sz = length(listCoefficients);
            newNameCoef = cell(sz,1);
            newNameErrCoef = cell(sz,1);
            for i = 1:sz
                newNameCoef{i} = app.duplicateVar(listCoefficients{i});
                newNameErrCoef{i} = app.duplicateVar(strcat('err',listCoefficients{i}));
            end
            newNameSSE =  app.duplicateVar('SSE');
            newNameRSquare =  app.duplicateVar('RSquare');
            newNameDFE =  app.duplicateVar('DFE');
            newNameAdjRSquare =  app.duplicateVar('AdjRSquare');
            newNameRMSE =  app.duplicateVar('RMSE');
            StartPoint = app.Fitting.StartPoint;
            Problem = app.Fitting.Problem;
            LowerBound = app.Fitting.LowerBound;
            UpperBound = app.Fitting.UpperBound;
            percent = app.Fitting.Percent;
            [FilterNames,~,CellNames] = app.getItems;
            [~,FilterName0,VarName0] = app.getValues;
            for CellName = CellNames
                field = app.getValues(FilterName0,VarName0,CellName);
                data = field.data;
                fitted = app.ForceCurves.(CellName{:}).Fitted;
                N = (size(data,1)*length(CellNames));
                for row = 1:size(data,1)
                    tStart = tic;
                    for col = 1:size(data,2)
                        try
                            if ~isnan(data(row,col))
                                if idx
                                    VarName = app.Fitting.vName;
                                    p = app.Var.(FilterName0).(VarName).(CellName{:}).data(row,col);
                                    Problem(end,1) = {p};
                                end
                                fitted{row,col} = app.doForceFit(myfit,StartPoint,LowerBound,UpperBound,Problem,percent,row,col,CellName{:});
                                c = coeffvalues(fitted{row,col});
                                err = confint(fitted{row,col});
                                err = (err(2,:) - err(1,:))/2;
                            else
                                c(1:sz) = NaN;
                                err(1:sz) = NaN;
                            end
                        catch
                            c(1:sz) = NaN;
                            err(1:sz) = NaN;
                        end
                        for FilterName = FilterNames
                            for i = 1:sz
                                newfield = app.getValues(FilterName,newNameCoef{i},CellName);
                                newfield.data(row,col) = c(i);
                                app.setValues(newfield,FilterName,newNameCoef{i},CellName);
                                newfield2 = app.getValues(FilterName,newNameErrCoef{i},CellName);
                                newfield2.data(row,col) = err(i)/c(i)*100;
                                app.setValues(newfield2,FilterName,newNameErrCoef{i},CellName);
                            end
                            gof = app.ForceCurves.(CellName{:}).Gof{row,col};

                            if isempty(gof)
                                gof.sse = NaN;
                                gof.rsquare = NaN;
                                gof.dfe = NaN;
                                gof.adjrsquare = NaN;
                                gof.rmse = NaN;
                            end
                            newfield = app.getValues(FilterName,newNameSSE,CellName);
                            newfield.data(row,col) = gof.sse;
                            app.setValues(newfield,FilterName,newNameSSE,CellName);
                            newfield = app.getValues(FilterName,newNameRSquare,CellName);
                            newfield.data(row,col) = gof.rsquare;
                            app.setValues(newfield,FilterName,newNameRSquare,CellName);
                            newfield = app.getValues(FilterName,newNameDFE,CellName);
                            newfield.data(row,col) = gof.dfe;
                            app.setValues(newfield,FilterName,newNameDFE,CellName);
                            newfield = app.getValues(FilterName,newNameAdjRSquare,CellName);
                            newfield.data(row,col) = gof.adjrsquare;
                            app.setValues(newfield,FilterName,newNameAdjRSquare,CellName);
                            newfield = app.getValues(FilterName,newNameRMSE,CellName);
                            newfield.data(row,col) = gof.rmse;
                            app.setValues(newfield,FilterName,newNameRMSE,CellName);
                        end
                    end
                    n = n + 1;
                    t = toc(tStart);
                    waitbar(n/N,h,{'Batch processing...',['Remaining time: ' num2str(round(t*(N-n)/60)) ' min.']});
                end
            end
            close(h);
            pause(0.1)
            clear h
            app.CalculateAndUpdate
        end

        function isContactPoint(app)
            [~,VarNames] = app.getItems;
            [indxContactPoint,tf] = listdlg('PromptString',{'Select the contact point','Cancel if none'},...
                'ListString',VarNames,'SelectionMode','single');
            if tf
                app.ContactPointName = VarNames{indxContactPoint};
                [~,~,CellNames] = app.getItems;
                for CellName = CellNames
                    field = app.getValues('Raw',app.ContactPointName,CellName);
                    contactpoint = field.data;


                    if ~isfield(app.ForceCurves.(CellName{:}),'height0') && isfield(app.ForceCurves.(CellName{:}),'height')
                        app.ForceCurves.(CellName{:}).height0 = app.ForceCurves.(CellName{:}).height;
                    end
                    if isfield(app.ForceCurves.(CellName{:}),'height0')
                        h = app.ForceCurves.(CellName{:}).height0;
                        app.ForceCurves.(CellName{:}).height = -(h-contactpoint);
                    end
                    app.ForceCurves.(CellName{:}).Fitted = cell(size(contactpoint));
                    app.ForceCurves.(CellName{:}).Gof = cell(size(contactpoint));
                    app.ForceCurves.(CellName{:}).condHeight = cell(size(contactpoint));
                end
%                 app.FittingfromcontactpointMenu.Enable = true;
                app.CurvatureMenu.Enable = true;
            else
                app.ContactPointName = [];
                [~,~,CellNames] = app.getItems;
                for CellName = CellNames
                    if isfield(app.ForceCurves.(CellName{:}),'height0')
                        h = app.ForceCurves.(CellName{:}).height0;
                        app.ForceCurves.(CellName{:}).height = -(h-h(:,:,1));
                        app.ForceCurves.(CellName{:}).Fitted = cell(size(h));
                        app.ForceCurves.(CellName{:}).Gof = cell(size(h));
                        app.ForceCurves.(CellName{:}).condHeight = cell(size(h));
                    end
                end
%                 app.FittingfromcontactpointMenu.Enable = false;
                app.CurvatureMenu.Enable = false;
            end
            figure(app.CellMAPUIFigure)
        end

        function [CP,newforce,row,col] = findContactPoint(app,row,col,CellName)
            if nargin == 1
                [field,~,~,CellName] = app.getValues;
                data = field.data;
                nrow = size(data,1);
                xSlider = floor(app.SliderCursorX.Value);
                ySlider = floor(app.SliderCursorY.Value);
                row = nrow - ySlider + 1;
                col = xSlider;
            end

            field = app.ForceCurves.(CellName);
            Force = squeeze(field.force(row,col,:));
            Height = squeeze(field.height(row,col,:));
            params = app.NewContactPoint;

            [CP, newforce] = afmContactPoint(Force,Height,params);
        end

        function doBatchContactPoint(app)
            h = waitbar(0,'Batch processing...');
            newNameCP =  app.duplicateVar('New_contact_point');
            [FilterNames,~,CellNames] = app.getItems;
            [field,FilterName0,VarName0] = app.getValues;
            sizeRow = size(field.data,1);
            sizeCol = size(field.data,2);
            n = 0;
            N = (sizeRow*length(CellNames));
            for CellName = CellNames
                field = app.getValues(FilterName0,VarName0,CellName);
                data = field.data;
                for row = 1:sizeRow
                    tStart = tic;
                    for col = 1:sizeCol
                        try
                            if ~isnan(data(row,col))
                                CP = app.findContactPoint(row,col,CellName{:});
                            else
                                CP = NaN;
                            end
                        catch
                            CP = NaN;
                        end
                        for FilterName = FilterNames
                            newfield = app.getValues(FilterName,newNameCP,CellName);
                            fieldOldCP = app.getValues(FilterName,app.ContactPointName,CellName);
                            newfield.data(row,col) = -CP+fieldOldCP.data(row,col);
                            app.setValues(newfield,FilterName,newNameCP,CellName);
                        end
                    end
                    n = n + 1;
                    t = toc(tStart);
                    waitbar(n/N,h,{'Batch processing...',['Remaining time: ' num2str(round(t*(N-n)/60)) ' min.']});
                end
            end
            close(h);
            pause(0.1)
            clear h
            app.CalculateAndUpdate
            app.isContactPoint
        end

        function deletePoint(app)
            [field,FilterName] = app.getValues;
            data = field.data;
            nrow = size(data,2);
            xSlider = floor(app.SliderCursorX.Value);
            ySlider = floor(app.SliderCursorY.Value);
            row = nrow - ySlider + 1;
            col = xSlider;
            [~,VarNames,CellNames] = app.getItems;
            for VarName = VarNames
                for CellName = CellNames
                    field = app.getValues(FilterName,VarName,CellName);
                    field.data(row,col) = NaN;
                    app.setValues(field,FilterName,VarName,CellName)
                end
            end
            app.CalculateAndUpdate
        end

        % function delete(app)
        %     % Delete ROI listeners
        %     if isfield(app,'ROIListeners') && ~isempty(app.ROIListeners)
        %         for k = 1:numel(app.ROIListeners)
        %             L = app.ROIListeners{k};
        %             if isvalid(L)
        %                 try delete(L); catch, end
        %             end
        %         end
        %         app.ROIListeners = {};
        %     end
        % 
        %     % any other cleanup...
        %     delete@matlab.apps.AppBase(app); % if appropriate
        % end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            appFolder = fileparts(mfilename('fullpath'));
            srcFolder = fullfile(appFolder, 'src');
            if isfolder(srcFolder)
                addpath(genpath(srcFolder));
            end
        end

        % Value changed function: CellnSpinner
        function CellnSpinnerValueChanged(app, event)
            % This function allows the user to display different cells
            app.CellnSpinner.Value = floor(app.CellnSpinner.Value);
            app.DropDownCell.Value = app.DropDownCell.Items{app.CellnSpinner.Value};
            UIAxesMapUpdate(app)
            UIAxesHistoUpdate(app)
        end

        % Value changed function: DropDownVariable
        function DropDownVariableValueChanged(app, event)
            % This function allows the user to change the variable imaged
            % (Young modulus, contact point, ...)
            %% Save parameters of scaling for the map
            oldvar = event.PreviousValue;
            [field,FilterName,VarName] = app.getValues;
            data = field.data;
            m = min(data(:));
            M = max(data(:));
            app.displayMap.(FilterName).(oldvar)(1:3) = [app.FixedCheckBoxMap.Value app.EditFieldMapLow.Value app.EditFieldMapHigh.Value];
            app.displayHisto.(FilterName).(oldvar)(1:4) = [app.FixedCheckBoxHisto.Value app.EditFieldHistoLow.Value app.EditFieldHistoHigh.Value app.EditFieldBinWidth.Value];
            %% Mapping
            if isfield(app.displayMap.(FilterName),VarName)
                app.FixedCheckBoxMap.Value = app.displayMap.(FilterName).(VarName)(1);
                app.EditFieldMapLow.Value = app.displayMap.(FilterName).(VarName)(2);
                app.EditFieldMapHigh.Value = app.displayMap.(FilterName).(VarName)(3);
            else
                app.FixedCheckBoxMap.Value = 0;
                
                
                app.EditFieldMapLow.Value = m;
                app.EditFieldMapHigh.Value = M;
            end
            UIAxesMapUpdate(app)
            if isfield(app.displayHisto.(FilterName),VarName)
                app.FixedCheckBoxHisto.Value = app.displayHisto.(FilterName).(VarName)(1);
                app.EditFieldHistoLow.Value = app.displayHisto.(FilterName).(VarName)(2);
                app.EditFieldHistoHigh.Value = app.displayHisto.(FilterName).(VarName)(3);
                app.EditFieldBinWidth.Value = app.displayHisto.(FilterName).(VarName)(4);
            else
                app.FixedCheckBoxHisto.Value = 0;
                app.EditFieldHistoLow.Value = m;
                app.EditFieldHistoHigh.Value = M;
                app.EditFieldBinWidth.Value = (M-m)/10;
            end
            UIAxesHistoUpdate(app)
            if ~isempty(app.ROIManagerFig)
                app.ButtonDoItStatPushed
            end
        end

        % Value changed function: EditFieldMapLow
        function EditFieldMapLowValueChanged(app, event)
            if event.Value >= app.EditFieldMapHigh.Value
                app.EditFieldMapLow.Value = event.PreviousValue;
            else
                oldval = app.FixedCheckBoxMap.Value;
                app.FixedCheckBoxMap.Value = 1;
                UIAxesMapUpdate(app)
                app.FixedCheckBoxMap.Value = oldval;
            end
        end

        % Value changed function: EditFieldMapHigh
        function EditFieldMapHighValueChanged(app, event)
            if event.Value <= app.EditFieldMapLow.Value
                app.EditFieldMapHigh.Value = event.PreviousValue;
            else
                oldval = app.FixedCheckBoxMap.Value;
                app.FixedCheckBoxMap.Value = 1;
                UIAxesMapUpdate(app)
                app.FixedCheckBoxMap.Value = oldval;
            end
        end

        % Value changed function: EditFieldHistoHigh
        function EditFieldHistoHighValueChanged(app, event)
            if event.Value <= app.EditFieldHistoLow.Value
                app.EditFieldHistoHigh.Value = event.PreviousValue;
            else
                oldval = app.FixedCheckBoxHisto.Value;
                app.FixedCheckBoxHisto.Value = 1;
                UIAxesHistoUpdate(app)
                app.FixedCheckBoxHisto.Value = oldval;
            end
        end

        % Value changed function: EditFieldHistoLow
        function EditFieldHistoLowValueChanged(app, event)
            if event.Value >= app.EditFieldHistoHigh.Value
                app.EditFieldHistoLow.Value = event.PreviousValue;
            else
                oldval = app.FixedCheckBoxHisto.Value;
                app.FixedCheckBoxHisto.Value = 1;
                UIAxesHistoUpdate(app)
                app.FixedCheckBoxHisto.Value = oldval;
            end
        end

        % Value changed function: EditFieldBinWidth
        function EditFieldBinWidthValueChanged(app, event)
            m = app.EditFieldHistoLow.Value;
            M = app.EditFieldHistoHigh.Value;
            BinWidth = app.EditFieldBinWidth.Value;
            if BinWidth > (M-m) || BinWidth <= 0
                app.EditFieldBinWidth.Value = event.PreviousValue;
            else
                oldval = app.FixedCheckBoxHisto.Value;
                app.FixedCheckBoxHisto.Value = 1;
                UIAxesHistoUpdate(app)
                app.FixedCheckBoxHisto.Value = oldval;
            end
        end

        % Value changed function: DropDownNormalization
        function DropDownNormalizationValueChanged(app, event)
            oldval = app.FixedCheckBoxHisto.Value;
            app.FixedCheckBoxHisto.Value = 1;
            UIAxesHistoUpdate(app)
            app.FixedCheckBoxHisto.Value = oldval;
        end

        % Button pushed function: ButtonDoitLeveling
        function ButtonDoitLevelingPushed(app, event)
            [~,FilterName,VarName] = app.getValues;
            desc = "Leveling from " + VarName +...
                " in " + FilterName + " maps";
            newFilterName = app.duplicateFilter('Lev',desc);

            [~,~,CellNames] = app.getItems;
            for CellName = CellNames
                field = app.getValues(FilterName,VarName,CellName);
                data = field.data;
                data_leveled = leveling(data);
                field.data = data_leveled;
                app.setValues(field,newFilterName,VarName,CellName)

                %% Calculate the indentation
                if isfield(app.Var.('Raw'),'Indentation')
                    fieldRawVar = app.getValues('Raw',VarName,CellName);
                    dataVar = fieldRawVar.data;
                    fieldRawIndent = app.getValues('Raw','Indentation',CellName);
                    dataIndent = fieldRawIndent.data;
                    newIndent = dataVar-dataIndent;
                    fieldNew = app.getValues(newFilterName,'Indentation',CellName);
                    fieldNew.data = newIndent./data_leveled*100;
                    fieldNew.unit = '%';
                    app.setValues(fieldNew,newFilterName,'Indentation',CellName)
                end
            end

            app.CalculateAndUpdate

            if ~verLessThan('matlab','9.8')
                app.ButtonROILeveling.Visible = 'on';
            end

            if strcmp(app.RecordMenu.Text,'Stop recording') % If it is recording
                app.Pipeline{end+1} = {'DropDownFilter','Value',FilterName};
                app.Pipeline{end+1} = {'DropDownVariable','Value',VarName};
                app.Pipeline{end+1} = 'ButtonDoitLevelingPushed';
            end
        end

        % Value changed function: DatacursorCheckBox
        function DatacursorCheckBoxValueChanged(app, event)
            value = app.DatacursorCheckBox.Value;
            field = app.getValues;
            data = field.data;
            if value
                app.SliderCursorX.Visible = 'on';
                app.SliderCursorX.Limits = [1 size(data,2)];
                app.SliderCursorY.Visible = 'on';
                app.SliderCursorY.Limits = [1 size(data,1)];
                app.EditFieldCursor.Visible = 'on';
                app.EditFieldCoordX.Visible = 'on';
                app.EditFieldCoordY.Visible = 'on';
                app.ImageKeyboard.Visible = 'on';
                app.unitLabel_12.Visible = 'on';
            else
                app.SliderCursorX.Visible = 'off';
                app.SliderCursorY.Visible = 'off';
                app.EditFieldCursor.Visible = 'off';
                app.EditFieldCoordX.Visible = 'off';
                app.EditFieldCoordY.Visible = 'off';
                app.ImageKeyboard.Visible = 'off';
                app.unitLabel_12.Visible = 'off';
            end
            app.UIAxesMapUpdate
        end

        % Value changed function: SliderCursorX
        function SliderCursorXValueChanged(app, event)
            app.UIAxesMapUpdate
        end

        % Value changed function: SliderCursorY
        function SliderCursorYValueChanged(app, event)
            UIAxesMapUpdate(app)
        end

        % Button pushed function: ButtonDoitThres
        function ButtonDoitThresPushed(app, event)
            [~,FilterName,VarNameThres] = app.getValues;
            m = app.EditFieldLowThres.Value;
            M = app.EditFieldUpThres.Value;
            desc = VarNameThres + " from " + FilterName +" maps: threshold for values in between " +...
                num2str(m) + " and "+ num2str(M)+app.UnitEditField.Value;
            newFilterName = app.duplicateFilter('Thr',desc);

            [~,VarNames,CellNames] = app.getItems;
            for CellName = CellNames
                field = app.getValues(FilterName,VarNameThres,CellName);
                [~,cond] = thresholding(field,[m,M]);
                for VarName = VarNames
                    field = app.getValues(FilterName,VarName,CellName);
                    newfield = thresholding(field,cond);
                    app.setValues(newfield,newFilterName,VarName,CellName)
                end
            end

            app.CalculateAndUpdate

            if strcmp(app.RecordMenu.Text,'Stop recording') % If it is recording
                app.Pipeline{end+1} = {'DropDownFilter','Value',FilterName};
                app.Pipeline{end+1} = {'DropDownVariable','Value',VarName};
                app.Pipeline{end+1} = {'EditFieldLowThres','Value',m};
                app.Pipeline{end+1} = {'EditFieldUpThres','Value',M};
                app.Pipeline{end+1} = 'ButtonDoitThresPushed';
            end
        end

        % Value changed function: CheckBoxHisto
        function CheckBoxHistoValueChanged(app, event)
            value = app.CheckBoxHisto.Value;
            if value
                app.SliderHisto.Visible = 'on';
                app.ImageMouse.Visible = 'on';
            else
                app.SliderHisto.Visible = 'off';
                app.ImageMouse.Visible = 'off';
            end
            app.UIAxesHistoUpdate
            app.UIAxesMapUpdate
        end

        % Callback function
        function SpinnerHistoValueChanged(app, event)
            app.SpinnerHisto.Value = floor(app.SpinnerHisto.Value);
            UIAxesHistoUpdate(app)
            UIAxesMapUpdate(app)
        end

        % Button pushed function: DeleteButtonFilter
        function DeleteButtonFilterPushed(app, event)
            [~,FilterName] = app.getValues;
            if ~strcmp(FilterName,'Raw') && ~strcmp(app.GeometryThresholdcurveMenu.Tag,'on')
                answer = questdlg('Are you sure?', ...
                    'Delete variable', ...
                    'Yes','No','No');
                switch answer
                    case 'Yes'
                        app.Var = rmfield(app.Var,{FilterName});
                        idx = contains(app.DropDownFilter.Items,FilterName);
                        app.DropDownFilter.Items(idx) = [];
                        app.DropDownFilter.Value = app.DropDownFilter.Items{end};
                        DropDownFilterValueChanged(app)
                    case 'No'
                end
            elseif strcmp(app.GeometryThresholdcurveMenu.Tag,'on')
                app.Var = rmfield(app.Var,{FilterName});
                idx = contains(app.DropDownFilter.Items,FilterName);
                app.DropDownFilter.Items(idx) = [];
                app.DropDownFilter.Value = app.DropDownFilter.Items{end};
                DropDownFilterValueChanged(app)
            else
                msgbox('You cannot delete raw data')
            end
        end

        % Value changed function: DropDownFilter
        function DropDownFilterValueChanged(app, event)
            UIAxesMapUpdate(app)
            UIAxesHistoUpdate(app)
        end

        % Button pushed function: ButtonDoitCleaning
        function ButtonDoitCleaningPushed(app, event)
            [~,FilterName,VarNameClean] = app.getValues;
            n = app.EditFieldNumPix.Value;
            desc = strcat('Cleaning of ',FilterName,...
                'maps for region smaller than ',num2str(n),' pixels');
            newFilterName = app.duplicateFilter('Cle',desc);

            [~,VarNames,CellNames] = app.getItems;
            for CellName = CellNames
                field = app.getValues(FilterName,VarNameClean,CellName);
                [~,cond] = cleaning(field,n);
                for VarName = VarNames
                    field = app.getValues(FilterName,VarName,CellName);
                    newfield = cleaning(field,cond);
                    app.setValues(newfield,newFilterName,VarName,CellName)
                end
            end

            app.CalculateAndUpdate

            if strcmp(app.RecordMenu.Text,'Stop recording') % If it is recording
                app.Pipeline{end+1} = {'EditFieldNumPix','Value',n};
                app.Pipeline{end+1} = 'ButtonDoitCleaningPushed';
            end
        end

        % Button pushed function: ButtonDoitCentering
        function ButtonDoitCenteringPushed(app, event)
            [~,FilterName,VarNameCentering] = app.getValues;
            desc = "Centering of " + FilterName + ...
                " maps";
            newFilterName = app.duplicateFilter('Cen',desc);

            % concatenate the cells
            [~,~,CellNames] = app.getItems;
            data = [];
            for CellName = CellNames
                field = app.getValues(FilterName,VarNameCentering,CellName);
                data = catnan3(data,field.data);
            end
            % project
            proj = mean(data,3,'omitnan');
            % center of the projection
            [rp,cp] = getCenter(proj);

            % translate
            [~,VarNames,CellNames] = app.getItems;
            for CellName = CellNames
                field = app.getValues(FilterName,VarNameCentering,CellName);
                data = field.data;
                [r,c] = getCenter(data);
                for VarName = VarNames
                    field = app.getValues(FilterName,VarName,CellName);
                    newfield = centering(field,[r,c],[rp,cp]);
                    app.setValues(newfield,newFilterName,VarName,CellName)
                end
            end

            app.CalculateAndUpdate

            if strcmp(app.RecordMenu.Text,'Stop recording') % If it is recording
                app.Pipeline{end+1} = 'ButtonDoitCenteringPushed';
            end
        end

        % Button pushed function: ButtonDoitAveraging
        function ButtonDoitAveragingPushed(app, event)
            [~,VarNames,CellNames] = app.getItems;
            [subCellNames,answer] = getListCells(CellNames);
            if ~isempty(subCellNames)
                [~,FilterName] = app.getValues;
                desc = "Averaging of " + FilterName + " maps for the cells: " + answer;
                newFilterName = app.duplicateFilter('Ave',desc);
                for VarName = VarNames
                    data = [];
                    % concatenate
                    for CellName = subCellNames
                        field = app.getValues(FilterName,VarName,CellName);
                        data = catnan3(data,field.data);
                    end
                    % define the data 3d array to sub population of cells
                    for CellName = subCellNames
                        newfield = app.getValues(FilterName,VarName,CellName);
                        data_averaged = averaging(data);
                        newfield.data = data_averaged;
                        app.setValues(newfield,newFilterName,VarName,CellName)
                    end
                end

                app.CalculateAndUpdate

                if strcmp(app.RecordMenu.Text,'Stop recording') % If it is recording
                    app.Pipeline{end+1} = 'ButtonDoitAveragingPushed';
                end
            end

        end

        % Callback function
        function TopographyCheckBoxValueChanged(app, event)
            app.UIAxesMapUpdate
        end

        % Value changed function: PixelsizeEditField
        function PixelsizeEditFieldValueChanged(app, event)
            [~,FilterName0] = app.getValues;
            FilterNames = app.getItems;
            for FilterName = FilterNames
                app.DropDownFilter.Value = FilterName;
                app.Calculate()
            end
            app.DropDownFilter.Value = FilterName0;
            app.UIAxesMapUpdate
        end

        % Button pushed function: ButtonDoitPeakremoving
        function ButtonDoitPeakremovingPushed(app, event)
            [~,VarNames,CellNames] = app.getItems;
            [~,FilterName,VarNamePeakremoving] = app.getValues;

            step = app.EditFieldGradientstep.Value;
            desc = "Peak removing from " + FilterName +...
                " maps with the criteria gradient of " + num2str(step) + " in " + VarNamePeakremoving;
            newFilterName = app.duplicateFilter('Pea',desc);

            for CellName = CellNames
                r = 0;
                while ~isempty(r)
                    field = app.getValues(newFilterName,VarNamePeakremoving,CellName);
                    data = field.data;
                    [r,c,neighbor8] = peakremoving(data,step);
                    if ~isempty(r)
                        for VarName = VarNames
                            newfield = app.getValues(newFilterName,VarName,CellName);
                            newfield.data(r,c) = NaN;
                            app.setValues(newfield,newFilterName,VarName,CellName)
                        end
                        field.data(r,c) = neighbor8(r,c);
                        app.setValues(field,newFilterName,VarNamePeakremoving,CellName)
                    end
                end
            end

            app.CalculateAndUpdate

            if strcmp(app.RecordMenu.Text,'Stop recording') % If it is recording
                app.Pipeline{end+1} = {'DropDownFilter','Value',FilterName};
                app.Pipeline{end+1} = {'DropDownVariable','Value',VarNamePeakremoving};
                app.Pipeline{end+1} = {'EditFieldGradientstep','Value',step};
                app.Pipeline{end+1} = 'ButtonDoitPeakremovingPushed';
            end
        end

        % Button pushed function: DoitButton
        function DoitButtonPushed(app, event)
            [~,VarNames,CellNames] = app.getItems;
            [subCellNames,answer] = getListCells(CellNames);
            if ~isempty(subCellNames)
                [~,FilterName] = app.getValues;
                desc = "Subpopulation from " + FilterName + ": "+answer;
                newFilterName = app.duplicateFilter('Sub',desc);
                for VarName = VarNames
                    data = [];
                    % concatenate
                    for CellName = subCellNames
                        field = app.getValues(FilterName,VarName,CellName);
                        data = catnan3(data,field.data);
                    end
                    c = ceil(sqrt(numel(subCellNames)));
                    data = imtile(data,'GridSize',[c c]);
                    data(data==0) = NaN;
                    for CellName = subCellNames
                        newfield = app.getValues(FilterName,VarName,CellName);
                        newfield.data = data;
                        app.setValues(newfield,newFilterName,VarName,CellName)
                    end
                end
                app.CellnSpinner.Value = find(contains(CellNames,CellName{:}));
                app.DropDownCell.Value = CellName{:};
                app.CalculateAndUpdate
            end

            figure(app.CellMAPUIFigure)
        end

        % Button pushed function: HistogramFitterButton
        function HistogramFitterButtonPushed(app, event)
            oldval = app.FixedCheckBoxHisto.Value;
            app.DropDownNormalization.Value = 'pdf';
            app.FixedCheckBoxHisto.Value = 1;
            UIAxesHistoUpdate(app)
            app.FixedCheckBoxHisto.Value = oldval;
            x = app.UIAxesHisto.Children(1).BinEdges(1:end-1)+app.UIAxesHisto.Children(1).BinWidth/2;
            data = app.UIAxesHisto.Children(1).Data;
            distname = {'BirnbaumSaunders','Exponential','ExtremeValue',...
            	'Gamma','GeneralizedExtremeValue','GeneralizedPareto','HalfNormal','InverseGaussian',...
                'Kernel','Logistic','Loglogistic','Lognormal','Normal',...
                'Poisson','Rayleigh','Rician','tLocationScale','Weibull'};
            idx = listdlg('SelectionMode','single','ListString',distname);
            if ~isempty(idx)
                try
                    pd = fitdist(data,distname{idx});
                    disp(pd)
                    y = pdf(pd,0:x(end)/1000:x(end));
                    hold(app.UIAxesHisto,'on')
                    plot(app.UIAxesHisto,0:x(end)/1000:x(end),y,'-')
                    hold(app.UIAxesHisto,'off')
                catch ME
                    disp(ME.message)
                end
            end
            figure(app.CellMAPUIFigure)
        end

        % Button pushed function: ButtonDoitTopography
        function ButtonDoitTopographyPushed(app, event)
            f = figure;
            ax = axes(f);
            FilterName = app.DropDownFilter.Value;
            VarName = app.DropDownVariable.Value;
            CellName = app.DropDownCell.Value;
            dataCol = app.Var.(FilterName).(VarName).(CellName).data;
            unitC = app.Var.(FilterName).(VarName).(CellName).unit;
            VarNameHeight = app.DropDownVariable_2.Value;
            data = app.Var.(FilterName).(VarNameHeight).(CellName).data;
            unitZ = app.Var.(FilterName).(VarNameHeight).(CellName).unit;
            nrow = size(data,1);
            ncol = size(data,2);
            pixel = app.PixelsizeEditField.Value;
            m = app.EditFieldMapLow.Value;
            M = app.EditFieldMapHigh.Value;
            surf(ax,(1:ncol)*pixel,(1:nrow)*pixel,data,dataCol,'EdgeColor','none','FaceColor','interp')
            colormap copper
            c = colorbar;
            c.Label.String = [VarName ' [' unitC ']'];
            c.Label.Interpreter = 'none';
            caxis([m M])
            title(ax,CellName,'interpreter', 'none')
            xlabel('x [µm]')
            ylabel('y [µm]')
            zlabel(ax,[VarNameHeight ' [' unitZ ']'],'interpreter', 'none')
            if strcmp(app.RecordMenu.Text,'Stop recording') % If it is recording
                app.Pipeline{end+1} = {'DropDownFilter','Value',FilterName};
                app.Pipeline{end+1} = {'DropDownVariable','Value',VarName};
                app.Pipeline{end+1} = {'DropDownVariable_2','Value',VarNameHeight};
                app.Pipeline{end+1} = 'ButtonDoitTopographyPushed';
            end
        end

        % Button pushed function: DeleteButtoVar
        function DeleteButtoVarButtonPushed(app, event)
            answer = questdlg('Are you sure?', ...
                'Delete variable', ...
                'Yes','No','No');
            switch answer
                case 'Yes'
                    FilterName = app.DropDownFilter.Items;
                    VarName = app.DropDownVariable.Value;
                    for filter = FilterName
                        app.Var.(filter{:}) = rmfield(app.Var.(filter{:}),{VarName});
                    end
                    app.DropDownVariable.Items = fieldnames(app.Var.(filter{:}));
                    app.DropDownVariable_2.Items = fieldnames(app.Var.(filter{:}));
                    app.DropDownVariable.Value = app.DropDownVariable.Items{end};
                    app.DropDownVariable_2.Value = app.DropDownVariable.Items{1};
                    UIAxesMapUpdate(app)
                    UIAxesHistoUpdate(app)
                case 'No'
            end
        end

        % Button pushed function: DeleteButtonCell
        function DeleteButtonCellPushed(app, event)
            answer = questdlg('Are you sure?', ...
                'Delete variable', ...
                'Yes','No','No');
            switch answer
                case 'Yes'
                    FilterName = app.DropDownFilter.Items;
                    VarName = app.DropDownVariable.Items;
                    CellName = app.DropDownCell.Value;
                    for var = VarName
                        for filter = FilterName
                            app.Var.(filter{:}).(var{:}) = rmfield(app.Var.(filter{:}).(var{:}),{CellName});
                        end
                    end
                    % Next line to be corrected
                    app.DropDownCell.Items(app.DropDownCell.ValueIndex)=[];
                    app.DropDownCell.Value = app.DropDownCell.Items{1};
                    app.CellnSpinner.Limits(2) = max(1.1,numel(app.DropDownCell.Items));
                    app.CellnSpinner.Value = numel(app.DropDownCell.Items);
                    app.N_cellEditField.Value = numel(app.DropDownCell.Items);
                    CalculateAndUpdate(app)
                    % UIAxesMapUpdate(app)
                    % UIAxesHistoUpdate(app)
                case 'No'
            end
        end

        % Button pushed function: ConvertButton
        function ConvertButtonPushed(app, event)
            prompt = {['Enter the name of the new unit, the current one is ' ...
                app.UnitEditField.Value ' :'],'Conversion factor (from m to µm, type 1e6, because 1 m = 10^6 µm):'};
            dlgtitle = 'Unit conversion';
            dims = [1 70];
            definput = {app.UnitEditField.Value,'1'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            if ~isempty(answer)
                FilterName = app.DropDownFilter.Value;
                VarName = app.DropDownVariable.Value;
                CellName = fieldnames(app.Var.(FilterName).(VarName))';
                for cellname = CellName
                    if isstruct(app.Var.(FilterName).(VarName).(cellname{:}))
                        app.Var.(FilterName).(VarName).(cellname{:}).data = app.Var.(FilterName).(VarName).(cellname{:}).data*str2double(answer{2});
                        app.Var.(FilterName).(VarName).(cellname{:}).unit = answer{1};
                    end
                end
                Calculate(app)
                UIAxesMapUpdate(app)
                UIAxesHistoUpdate(app)
                if strcmp(app.RecordMenu.Text,'Stop recording') % If it is recording
                    app.Pipeline{end+1} = 'ConvertButtonPushed';
                end
            end

        end

        % Button pushed function: ButtonROILeveling
        function ButtonROILevelingPushed(app, event)
            app.ROILabel.Visible = true;
            FilterName = app.DropDownFilter.Value;
            VarName = app.DropDownVariable.Value;
            CellName = app.DropDownCell.Value;
            % app.DropDownFilter.Value = 'Raw';
            UIAxesMapUpdate(app)

            roi = drawfreehand(app.UIAxesMap);
            while isvalid(roi)
                wait(roi)
                if ~isvalid(roi)
                    roi = drawfreehand(app.UIAxesMap);
                else
                    break
                end
            end
            binaryImage = roi.createMask();
            z = app.Var.(FilterName).(VarName).(CellName).data(binaryImage);
            [x,y]=find(binaryImage);
            %% ADD THIS IN A SEPERATE CODE!
            Coef = [x(:) y(:) ones(size(x(:)))]\z(:);
            data = app.Var.(FilterName).(VarName).(CellName).data;
            [M,N] = meshgrid(0:size(data,2)-1,0:size(data,1)-1);
            dataref = Coef(1)*N + Coef(2)*M + Coef(3);
            app.Var.(FilterName).(VarName).(CellName).data = data - dataref;
            %%
            app.DropDownFilter.Value = FilterName;
            UIAxesMapUpdate(app)
            UIAxesHistoUpdate(app)
            app.ROILabel.Visible = false;
        end

        % Value changed function: SliderHisto
        function SliderHistoValueChanged(app, event)
            app.SliderHisto.Value = floor(app.SliderHisto.Value);
            UIAxesHistoUpdate(app)
            UIAxesMapUpdate(app)
        end

        % Key press function: CellMAPUIFigure
        function CellMAPUIFigureKeyPress(app, event)
            key = event.Key;
            if app.DatacursorCheckBox.Value
                switch key
                    case 'rightarrow'
                        app.SliderCursorX.Value = min(app.SliderCursorX.Value + 1,app.SliderCursorX.Limits(2));
                    case 'leftarrow'
                        app.SliderCursorX.Value = max(app.SliderCursorX.Value - 1,1);
                    case 'uparrow'
                        app.SliderCursorY.Value = min(app.SliderCursorY.Value + 1,app.SliderCursorY.Limits(2));
                    case 'downarrow'
                        app.SliderCursorY.Value = max(app.SliderCursorY.Value - 1,1);
                end
                UIAxesMapUpdate(app)
            end
        end

        % Close request function: CellMAPUIFigure
        function CellMAPUIFigureCloseRequest(app, event)
            confirm = uiconfirm(app.CellMAPUIFigure,'Close CellMAP?','Confirm close','Icon','warning');
            if strcmp(confirm,'OK')
                delete(app.calledFittingApp)
                delete(app.calledForceApp)
                delete(app.calledContactPointApp)
                delete(app.calledHeightApp)
                delete(app.calledPlotYfXApp)
                delete(app)
            end
        end

        % Menu selected function: NewsessionMenu
        function NewsessionMenuSelected(app, event)
            %% This menu allows loading of new data

            % Warning to save the current session
            if ~isempty(app.Var)
                confirm = uiconfirm(app.CellMAPUIFigure,'If not saved, it will delete the current session.','Open new session','Icon','warning');
                if strcmp(confirm,'OK')
                    app.savepath = uigetdir; % Select a folder
                else
                    app.savepath = 0;
                end
            else
                app.savepath = uigetdir; % Select a folder
            end

            % Load the data
            if app.savepath ~= 0    % If selected
                % Initialize the program
                props = properties(app);
                for i = 1:length(props)
                    propName = props{i};
                    if isstruct(app.(propName))
                        app.(propName) = [];
                    end
                end

                cd(app.savepath)

                out = LoadingJPK(app.savepath);     % Load files in the given folder
                %% Update Variables
                if isfield(out,'Force')
                    app.ForceCurves = out.Force;
                    app.Var.Raw = rmfield(out,'Force');
                else
                    app.Var.Raw = out;
                end
                app.DropDownFilter.Items = {'Raw'};
                app.VarDescription.('Raw') = 'Raw data loaded';
                app.DropDownVariable.Items = fieldnames(app.Var.('Raw'));
                app.DropDownVariable_2.Items = fieldnames(app.Var.('Raw'));
                %% Update Spinners
                temp = fieldnames(app.Var.('Raw'));
                app.DropDownCell.Items = fieldnames(app.Var.('Raw').(temp{1}));
                nCell = numel(fieldnames(app.Var.('Raw').(temp{1})));
                app.CellnSpinner.Limits = [1 max(1.1,nCell)];
                app.N_cellEditField.Value = nCell;

                field = app.getValues;
                app.SliderCursorX.Limits = [1 size(field.data,2)];
                app.SliderCursorY.Limits = [1 size(field.data,1)];
                app.SliderCursorX.Value = floor(size(field.data,2)/2);
                app.SliderCursorY.Value = floor(size(field.data,1)/2);
                app.PixelsizeEditField.Value = field.pixel;

                if isfield(out,'Force')
                    app.LoadforcecurvesMenu.Enable = true;
                    app.BatchprocessingofcontactpointMenu.Enable = false;
                    app.FittingfromcontactpointMenu.Enable = false;
                    [~,~,CellNames] = app.getItems;
                    if isfield(app.ForceCurves.(CellNames{1}),'height')
                        for CellName = CellNames
                            app.ForceCurves.(CellName{:}).height0 = app.ForceCurves.(CellName{:}).height;
                        end
                        app.LoadforcecurvesMenu.Enable = false;
                        app.BatchprocessingofcontactpointMenu.Enable = true;
                        app.FittingfromcontactpointMenu.Enable = true;
                    end
                    app.isContactPoint
                else
                    app.LoadforcecurvesMenu.Enable = false;
                    app.BatchprocessingofcontactpointMenu.Enable = false;
                    app.FittingfromcontactpointMenu.Enable = false;
                end
                %% Calculate Geometrical Factors
                Calculate(app)
                %% Mapping
                UIAxesMapUpdate(app)
                %% Histogram
                UIAxesHistoUpdate(app)
                %% Remove logo visibility
                app.Image.Visible = 'off';
                app.Copyright.Visible = 'off';
                app.Image2.Visible = 'off';
                %% Enable menu
                app.ExportMenu.Enable = 'on';
                app.AddonMenu.Enable = 'on';
                app.MenuHelp.Enable = 'on';
            end
            figure(app.CellMAPUIFigure) % Display the app
        end

        % Menu selected function: ExportSessionMenu
        function ExportSessionMenuSelected(app, event)
            propNames = properties(app);

            propList = {'Items','Limits','Visible','Enable',...
                'Value'};
            prop2save = cell(length(propNames),length(propList));
            struct2save = struct();

            for i = 1:length(propNames)
                propName = propNames{i};
                property = app.(propName);
                if isobject(property)
                    for j = 1:length(propList)
                        if isprop(property,propList{j}) && ~isprop(property,'ColumnSpacing')
                            prop2save{i,j} = app.(propName).(propList{j});
                        end
                    end
                else
                    struct2save.(propName) = app.(propName);
                end
            end
            [file, path] = uiputfile(fullfile(app.savepath,['CellMAP-' datestr(now,'yyyy-mm-dd-HH-MM') '.mat']), "Save the session" );
            if file
                save([path file],'propNames','prop2save','struct2save','propList','-v7.3');
            end

            figure(app.CellMAPUIFigure) % Display the app
        end

        % Menu selected function: LoadsessionMenu
        function LoadsessionMenuSelected(app, event)
            % This function allows loading of an already saved session

            [file,path] = uigetfile('*.mat');
            if file
                warning('off')
                load(fullfile(path,file),'propNames','prop2save','struct2save','propList')
                warning('on')
                if ~exist('propNames','var')
                    propNames = properties(app);
                end

                % Store internally the saved session
                for i = 1:length(propNames)
                    propName = propNames{i};
                    if isprop(app,propName)
                        property = app.(propName);
                        % if i == 70
                        %     disp(i)
                        % end
                        if isobject(property)
                            for j = 1:length(propList)
                                if isprop(property,propList{j}) && ~isprop(property,'ColumnSpacing')
                                    app.(propName).(propList{j}) = prop2save{i,j};
                                    % disp(['i = ', num2str(i)])
                                    % disp(['j = ', num2str(j)])
                                end
                            end
                        else
                            app.(propName) = struct2save.(propName);
                        end
                    end
                end
                %% Remove logo visibility
                app.Image.Visible = 'off';
                app.Copyright.Visible = 'off';
                app.Image2.Visible = 'off';
                
                % Update the axes
                UIAxesMapUpdate(app)
                UIAxesHistoUpdate(app)
            end

            figure(app.CellMAPUIFigure) % Display the app
        end

        % Menu selected function: CurrentmaphistogramMenu
        function CurrentmaphistogramMenuSelected(app, event)
            CurrenthistogramMenuSelected(app);
            CurrentmapMenuSelected(app);
        end

        % Menu selected function: CurrentmapMenu
        function CurrentmapMenuSelected(app, event)
            % Create new figure and new axes
            figure
            axNew = axes;
            set(gcf,'units','centimeters','position',[5 9 4 6])
            UIAxesMapUpdate(app,axNew)
            set(axNew,'Color','none','XTick','','YTick','')
            c = colorbar(axNew);
            set(c,'FontSize',9.9,'Location','southoutside','LineWidth',0.5,'Color',[0 0 0])
            FilterName = app.DropDownFilter.Value;
            VarName = app.DropDownVariable.Value;
            CellName = app.DropDownCell.Value;
            unitC = app.Var.(FilterName).(VarName).(CellName).unit;
            c.Label.String = [VarName ' [' unitC ']'];
            c.Label.Interpreter = 'none';
            c.Label.Color = [0 0 0];
            T = get(gca,'Title');
            set(T,'Color',[0 0 0])
            xlabel('x [pixel]')
            ylabel('y [pixel]')
        end

        % Menu selected function: CurrenthistogramMenu
        function CurrenthistogramMenuSelected(app, event)
            % Create new figure and new axes
            figure
            axNew = axes;
            set(gcf,'units','centimeters','position',[5 3 4 3])
            UIAxesHistoUpdate(app,axNew)
            t = get(app.UIAxesHisto,'Children');
            if length(t) > 1
                x = t(1).XData;
                y = t(1).YData;
                hold(axNew,'on')
                plot(axNew,x,y,'-')
            end
            set(axNew,'FontName','Helvetica','FontSize',9,'XColor',[0 0 0],...
                'YColor',[0 0 0],'Color','none','LineWidth',0.5,'GridColor',[0 0 0])
            h = get(axNew,'Children');
            set(h,'EdgeColor','auto','FaceColor','auto')
            FilterName = app.DropDownFilter.Value;
            VarName = app.DropDownVariable.Value;
            CellName = app.DropDownCell.Value;
            unitX = app.Var.(FilterName).(VarName).(CellName).unit;
            axNew.XLabel.String = [VarName ' [' unitX ']'];
            axNew.XLabel.Interpreter = 'none';
        end

        % Menu selected function: DataMenu
        function DataMenuSelected(app, event)
            if ~isdeployed
                exportData = app.Var;
                exportDesc = app.VarDescription;
                labels = {'All variable:','Description'};%,'Geometrical parameters:','Histogram parameters:',
                vars = {'maps','desc'};%'geom','histo'
                vals = {exportData,exportDesc};%,exportGeometry,exportHisto
                title = 'Save to Matlab Workspace?';

                h = export2wsdlg(labels,vars,vals,...
                    title);
                uiwait(h);
            end


            answer = questdlg(['Do you want to save .txt file? Saved in ' app.savepath], ...
                'Save', ...
                'Yes','No','Yes');
            switch answer
                case 'Yes'
                    if app.savepath ~= 0
                        [FilterNames,VarNames,CellNames] = app.getItems;
                        for FilterName = FilterNames
                            for VarName = VarNames
                                for CellName = CellNames
                                    field = app.getValues(FilterName,VarName,CellName);
                                    H = field.data;
                                    if ~exist(fullfile(app.savepath,FilterName{:},VarName{:},CellName{:}),'dir')
                                        mkdir(fullfile(app.savepath,FilterName{:},VarName{:},CellName{:}));
                                    end
                                    writecell(field.header,fullfile(app.savepath,FilterName{:},VarName{:},CellName{:},'map.txt'))
                                    writematrix(H,fullfile(app.savepath,FilterName{:},VarName{:},CellName{:},'map.txt'),'Delimiter',' ', 'WriteMode','append')
                                end
                            end
                        end
                    end
            end
        end

        % Menu selected function: GeometryThresholdcurveMenu
        function GeometryThresholdcurveMenuSelected(app, event)
            app.GeometryThresholdcurveMenu.Tag = 'on'; % This is used to bypass deletion window
            init = 0;
            step = 0.05;
            fin = 1;
            thres = init:step:fin;
            finUp = 20;
            app.EditFieldUpThres.Value = finUp;
            nCell = app.CellnSpinner.Limits(2);
            toparea = nan(nCell,length(thres));
            projarea = nan(nCell,length(thres));
            volume = nan(nCell,length(thres));
            for i = 1:length(thres)
                app.EditFieldLowThres.Value = thres(i);
                ButtonDoitThresPushed(app);
                FilterName = app.DropDownFilter.Value;
                VarName = app.DropDownVariable.Value;
                toparea(:,i) = app.TopArea.(FilterName).(VarName).all;
                projarea(:,i) = app.SurfaceProjected.(FilterName).(VarName).all;
                volume(:,i) = app.Volume.(FilterName).(VarName).all;
                DeleteButtonFilterPushed(app);
            end
            app.GeometryThresholdcurveMenu.Tag = 'off';
        end

        % Menu selected function: RecordMenu
        function RecordMenuSelected(app, event)
            % This function records the workflow defined by the user, which
            % can then be used later on another set of data.
            if strcmp(app.RecordMenu.Text,'Record') % If click on record = start recording
                app.RecordMenu.Text = 'Stop recording';
                app.Pipeline = {}; % Initialize the pipeline
            else % If stop recording
                app.RecordMenu.Text = 'Record';
                [file,path] = uiputfile(fullfile(app.savepath,['PipelineCellMAP-' datestr(now,'yyyy-mm-dd-HH-MM') '.mat']), "Save a pipeline" );
                if file
                    pipeline = app.Pipeline;
                    save(fullfile(path,file),'pipeline')
                end
            end

            figure(app.CellMAPUIFigure) % Display the app
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            [file,path] = uigetfile('*.mat');
            if file
                load(fullfile(path,file),'pipeline')
                for i = 1:length(pipeline)
                    if ~iscell(pipeline{i})
                        fp = str2func(pipeline{i});
                        fp(app);
                    else
                        tmp = pipeline{i};
                        app.(tmp{1}).(tmp{2}) = tmp{3};
                    end
                end
            end
        end

        % Menu selected function: DescriptionMenu
        function DescriptionMenuSelected(app, event)
            % This function gives the description of the variable.
            VarName = app.DropDownFilter.Value;
            msg = app.VarDescription.(VarName);
            uiwait(msgbox(msg,'modal'));
        end

        % Menu selected function: FittingfromcontactpointMenu
        function FittingfromcontactpointMenuSelected(app, event)
            if ~isempty(app.ContactPointName)
                app.DatacursorCheckBox.Value = true;
                app.DatacursorCheckBoxValueChanged
                app.UIAxesMapUpdate
                app.calledFittingApp = FittingApp(app,app.calledForceApp);
            end
        end

        % Window scroll wheel function: CellMAPUIFigure
        function CellMAPUIFigureWindowScrollWheel(app, event)
            %             verticalScrollAmount = event.VerticalScrollAmount;
            count = event.VerticalScrollCount;
            if app.CheckBoxHisto.Value && count > 0
                app.SliderHisto.Value = min(app.SliderHisto.Value + count,app.SliderHisto.Limits(2));
            end
            if app.CheckBoxHisto.Value && count < 0
                app.SliderHisto.Value = max(app.SliderHisto.Value - 1,1);
            end
            UIAxesHistoUpdate(app)
            UIAxesMapUpdate(app)
        end

        % Menu selected function: BatchprocessingofcontactpointMenu
        function BatchprocessingofcontactpointMenuSelected(app, event)
            app.DatacursorCheckBox.Value = true;
            app.ForcecurveCheckBox.Value = true;
            app.DatacursorCheckBoxValueChanged
            app.UIAxesMapUpdate
            app.calledContactPointApp = ContactPointApp(app,app.calledForceApp);
        end

        % Button pushed function: DistributionFitterfromMatlabButton
        function DistributionFitterfromMatlabButtonPushed(app, event)
            field = app.getValues;
            data = field.data;
            distributionFitter(data(:))
        end

        % Button pushed function: ButtonDoitHoleremoving
        function ButtonDoitHoleremovingPushed(app, event)
            [~,~,CellNames] = app.getItems;
            [~,FilterName,VarNameHoleremoving] = app.getValues;

            desc = "Hole removing from " + FilterName +...
                " maps in " + VarNameHoleremoving;
            newFilterName = app.duplicateFilter('Hol',desc);

            for CellName = CellNames
                field = app.getValues(newFilterName,VarNameHoleremoving,CellName);
                k = 1;
                while k > 0
                    [neighbor8,k] = holeremoving(field.data);
                    field.data = neighbor8;
                end
                app.setValues(field,newFilterName,VarNameHoleremoving,CellName)
            end

            app.CalculateAndUpdate

            if strcmp(app.RecordMenu.Text,'Stop recording') % If it is recording
                app.Pipeline{end+1} = {'DropDownFilter','Value',FilterName};
                app.Pipeline{end+1} = {'DropDownVariable','Value',VarNameHoleremoving};
                app.Pipeline{end+1} = 'ButtonDoitHoleremovingPushed';
            end
        end

        % Menu selected function: PlotYfXMenu
        function PlotYfXMenuSelected(app, event)
            app.calledPlotYfXApp = PlotYfX(app);
        end

        % Menu selected function: CurvatureMenu
        function CurvatureMenuSelected(app, event)
            VarName = app.ContactPointName;
            if ~isempty(VarName)
                [FilterNames,~,CellNames] = app.getItems;
                newNameGaussCurv = app.duplicateVar('GaussianCurvature');
                newNameMeanCurv = app.duplicateVar('MeanCurvature');
                for CellName = CellNames
                    for FilterName = FilterNames
                        field = app.getValues(FilterName,VarName,CellName);
                        Z = field.data;
                        [X,Y] = meshgrid(1:size(Z,1),1:size(Z,2));
                        X = X*field.pixel;
                        Y = Y*field.pixel;
                        fieldK = app.getValues(FilterName,newNameGaussCurv,CellName);
                        fieldH = app.getValues(FilterName,newNameMeanCurv,CellName);
                        [fieldK.data,fieldH.data] = surfature(X,Y,Z);
                        fieldK.unit = [field.unit '^-2'];
                        fieldH.unit = [field.unit '^-1'];
                        app.setValues(fieldK,FilterName,newNameGaussCurv,CellName);
                        app.setValues(fieldH,FilterName,newNameMeanCurv,CellName);
                    end
                end
            end
            app.DropDownVariable.Value = newNameMeanCurv;
            app.CalculateAndUpdate
        end

        % Value changed function: QuantileDropDown
        function QuantileDropDownValueChanged(app, event)
            app.CalculateAndUpdate
        end

        % Value changed function: ForcecurveCheckBox
        function ForcecurveCheckBoxValueChanged(app, event)
            app.UIAxesMapUpdate
        end

        % Menu selected function: LoadforcecurvesMenu
        function LoadforcecurvesMenuSelected(app, event)
            [~,~,CellNames] = app.getItems;
            quest = 'Do you want to estimate the time before loading?';
            quest_r = 1;
            while quest_r
                answerUnzip = questdlg(quest,'Load force curves','Yes','No','Abord','Yes');
                switch answerUnzip
                    case 'Yes'
                        h = waitbar(0.1,'Calculating... ');
                        tic
                        folder = app.ForceCurves.(CellNames{1}).jpkDir.folder;
                        name = app.ForceCurves.(CellNames{1}).jpkDir.name;
                        ReadJPK(folder,name);
                        t = toc;
                        close(h)
                        pause(.1)
                        clear h
                        uiwait(msgbox(['Time estimate: ' num2str(round(t*length(CellNames))) ' s.']));
                    case 'No'
                        h = waitbar(0.1,'Calculating... ');
                        n = 1;
                        for CellName = CellNames
                            tic
                            folder = app.ForceCurves.(CellName{:}).jpkDir.folder;
                            name = app.ForceCurves.(CellName{:}).jpkDir.name;
                            [indent,height,force] = ReadJPK(folder,name);
                            app.ForceCurves.(CellName{:}).force = force;
                            app.ForceCurves.(CellName{:}).height = height;
                            app.ForceCurves.(CellName{:}).indent = indent;
                            out.('Indentation').(CellName{:}).data = indent;
                            out.('Indentation').(CellName{:}).unit = 'µm';
                            out.('Indentation').(CellName{:}).pixel = 1;
                            t = toc;
                            waitbar(n/length(CellNames),h,['Remaining time: ' num2str(round(t*(length(CellNames)-n))) ' s.']);
                            n = n+1;
                        end
                        app.LoadforcecurvesMenu.Enable = false;
                        app.BatchprocessingofcontactpointMenu.Enable = true;
                        app.FittingfromcontactpointMenu.Enable = true;
                        quest_r = 0;
                        close(h)
                        pause(.1)
                        clear h
                        isContactPoint(app)
                    case 'Abord'
                        quest_r=0;
                end
            end
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            uiconfirm(app.CellMAPUIFigure,['<a href="https://link.springer.com/article/10.1186/s12859-025-06060-0">To cite this article: Allard, A., Liboz, M., ' ...
                'Crépin, R. et al. CellMAP: an open-source software tool to batch-process cell topography and ' ...
                'stiffness maps collected with an atomic force microscope. BMC Bioinformatics 26, 38 (2025). ' ...
                'https://doi.org/10.1186/s12859-025-06060-0</a>'],'About','Interpreter','html');
        end

        % Button pushed function: ButtonROIManager
        function ButtonROIManagerPushed(app, event)
            if isempty(app.ROIManagerFig) || ~isvalid(app.ROIManagerFig)
                app.ROIManagerFig = uifigure('Name', 'ROI Manager', 'Position', [100 100 300 400]);
                uilistbox(app.ROIManagerFig, ...
                    'Position', [10 70 280 320], ...
                    'Multiselect', 'on', ...
                    'Tag', 'ROIlb', ...
                    'ValueChangedFcn', @(src,event) app.onROISelectionChanged(src));
                uibutton(app.ROIManagerFig, 'Position', [10 35 80 25], ...
                    'Text', 'Add', ...
                    'ButtonPushedFcn', @(~,~) app.ButtonROIAddPushed([]));
                uibutton(app.ROIManagerFig, 'Position', [100 35 80 25], ...
                    'Text', 'Delete', ...
                    'ButtonPushedFcn', @(~,~) app.ButtonROIRemovePushed([]));
                uibutton(app.ROIManagerFig, 'Position', [190 35 100 25], ...
                    'Text', 'Compute', ...
                    'ButtonPushedFcn', @(~,~) app.ButtonDoItStatPushed([]));
                uibutton(app.ROIManagerFig, 'Position', [190 5 100 25], ...
                    'Text', 'Uncompute', ...
                    'ButtonPushedFcn', @(~,~) app.ButtonUndoItStatPushed([]));
            else
                figure(app.ROIManagerFig);
            end
            app.refreshROIListbox();
        end

        % Callback function
        function ButtonROIRemovePushed(app, event)
            % lb = findobj(app.ROIManagerFig, 'Tag', 'ROIlb');
            % if isempty(lb), return; end
            % sel = lb.Value;
            % if isempty(sel), return; end
            % if ischar(sel) || isstring(sel), sel = {char(sel)}; end
            % names = cellfun(@(s) s.name, app.ROIList, 'UniformOutput', false);
            % idx = find(ismember(names, sel));
            % for k = fliplr(idx)
            %     % Deleting the ROI object triggers onROIDeleted via listener
            %     if isvalid(app.ROIList{k}.obj)
            %         delete(app.ROIList{k}.obj);
            %     else
            %         % Object already gone; clean up manually
            %         app.onROIDeleted(app.ROIList{k}.name);
            %     end
            % end
            lb = findobj(app.ROIManagerFig,'Tag','ROIlb');
            sel = lb.Value;
            if isempty(sel), return; end
            if ischar(sel) || isstring(sel), sel = {char(sel)}; end
        
            % find indices in ROIList
            names = cellfun(@(s) s.name, app.ROIList, 'UniformOutput', false);
            idx = find(ismember(names, sel));
            for k = fliplr(idx)
                % if isvalid(app.ROIList{k}.obj), delete(app.ROIList{k}.obj); end
                % if isvalid(app.ROIList{k}.text), delete(app.ROIList{k}.text); end
                % remove ROIList entry
                app.ROIList(k) = [];
            end
        
            % Also remove entries from app.SavedROIs matching the deleted names
            if isprop(app, 'SavedROIs') && ~isempty(app.SavedROIs)
                toRemove = ismember({app.SavedROIs.name}, sel);
                app.SavedROIs(toRemove) = [];
                if isempty(app.SavedROIs), app.SavedROIs = []; end
            end
        
            % update listbox items
            if isempty(app.ROIManagerFig) || ~isvalid(app.ROIManagerFig), return; end
            lb = findobj(app.ROIManagerFig,'Tag','ROIlb');
            if isempty(lb), return; end
            names = cellfun(@(s) s.name, app.ROIList, 'UniformOutput', false);
            lb.Items = names;
            app.refreshROIListbox();
        end

        % Callback function
        function ButtonDoItStatPushed(app, event)
            lb = findobj(app.ROIManagerFig, 'Tag', 'ROIlb');
            if isempty(lb), return; end
            sel = lb.Value;
            if isempty(sel), return; end
            if ischar(sel) || isstring(sel), sel = {char(sel)}; end
            % Get current image dimensions
            field = app.getValues();
            imgSize = size(field.data);
            combinedMask = false(imgSize);
            for i = 1:numel(sel)
                name = sel{i};
                % Try SavedROIs first
                sidx = find(strcmp({app.SavedROIs.name}, name), 1);
                if ~isempty(sidx) && ~isempty(app.SavedROIs(sidx).mask) ...
                        && isequal(size(app.SavedROIs(sidx).mask), imgSize)
                    combinedMask = combinedMask | app.SavedROIs(sidx).mask;
                    continue
                end
                % Fall back to live ROI object
                lidx = find(cellfun(@(s) strcmp(s.name, name), app.ROIList), 1);
                if isempty(lidx), continue; end
                roi = app.ROIList{lidx}.obj;
                if isempty(roi) || ~isvalid(roi), continue; end
                try
                    mask = createMask(roi, field.data);
                catch
                    p = roi.Position;
                    mask = poly2mask(p(:,1), p(:,2), imgSize(1), imgSize(2));
                end
                combinedMask = combinedMask | mask;
                % Update saved mask
                app.SavedROIs(sidx).mask = mask;
            end
            if ~any(combinedMask(:))
                uialert(app.CellMAPUIFigure, 'No valid ROI masks.', 'ROI Error');
                return
            end
            app.CalculateAndUpdate(combinedMask);
            app.showSelectedROIs(sel);
        end

        % Callback function
        function ButtonROIAddPushed(app, event)
            % Increment counter and generate unique name
            app.ROIcount = app.ROIcount + 1;
            name = sprintf('ROI_%03d', app.ROIcount);
            % Draw freehand ROI
            roi = drawfreehand(app.UIAxesMap);
            wait(roi);
            % Handle cancel (empty position)
            if isempty(roi.Position)
                delete(roi);
                app.ROIcount = app.ROIcount - 1;
                return
            end
            % Add text label at centroid
            pos = mean(roi.Position, 1);
            txt = text(app.UIAxesMap, pos(1), pos(2), name, ...
                'Color', 'g', 'FontSize', 8, 'Interpreter', 'none');
            % Store in ROIList
            entry = struct('obj', roi, 'name', name, 'text', txt);
            app.ROIList{end+1} = entry;
            % Compute and save mask immediately
            img = getimage(app.UIAxesMap);
            if ~isempty(img)
                try
                    mask = createMask(roi, img);
                catch
                    p = roi.Position;
                    mask = poly2mask(p(:,1), p(:,2), size(img,1), size(img,2));
                end
            else
                mask = [];
            end
            saved = struct('name', name, 'mask', mask, 'position', roi.Position, ...
                           'type', class(roi), 'time', datetime('now'));
            if isempty(app.SavedROIs)
                app.SavedROIs = saved;
            else
                app.SavedROIs(end+1) = saved;
            end
            % Listener for deletion — ensure text is deleted and list updated
            lh = addlistener(roi, 'ObjectBeingDestroyed', @(~,~) app.onROIDeleted(name));
            app.ROIListeners{end+1} = lh;
            app.refreshROIListbox();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.CellMAPUIFigure = uifigure('Visible', 'off');
            app.CellMAPUIFigure.Name = 'CellMAP';
            app.CellMAPUIFigure.CloseRequestFcn = createCallbackFcn(app, @CellMAPUIFigureCloseRequest, true);
            app.CellMAPUIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @CellMAPUIFigureWindowScrollWheel, true);
            app.CellMAPUIFigure.KeyPressFcn = createCallbackFcn(app, @CellMAPUIFigureKeyPress, true);
            app.FileMenu = uimenu(app.CellMAPUIFigure);
            app.FileMenu.Text = 'File';
            app.NewsessionMenu = uimenu(app.FileMenu);
            app.NewsessionMenu.MenuSelectedFcn = createCallbackFcn(app, @NewsessionMenuSelected, true);
            app.NewsessionMenu.Text = 'New session';
            app.LoadsessionMenu = uimenu(app.FileMenu);
            app.LoadsessionMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadsessionMenuSelected, true);
            app.LoadsessionMenu.Text = 'Load session';
            app.ExportMenu = uimenu(app.CellMAPUIFigure);
            app.ExportMenu.Enable = 'off';
            app.ExportMenu.Text = 'Export';
            app.FiguresMenu = uimenu(app.ExportMenu);
            app.FiguresMenu.Text = 'Figures';
            app.CurrentmaphistogramMenu = uimenu(app.FiguresMenu);
            app.CurrentmaphistogramMenu.MenuSelectedFcn = createCallbackFcn(app, @CurrentmaphistogramMenuSelected, true);
            app.CurrentmaphistogramMenu.Text = 'Current map + histogram';
            app.CurrentmapMenu = uimenu(app.FiguresMenu);
            app.CurrentmapMenu.MenuSelectedFcn = createCallbackFcn(app, @CurrentmapMenuSelected, true);
            app.CurrentmapMenu.Text = 'Current map';
            app.CurrenthistogramMenu = uimenu(app.FiguresMenu);
            app.CurrenthistogramMenu.MenuSelectedFcn = createCallbackFcn(app, @CurrenthistogramMenuSelected, true);
            app.CurrenthistogramMenu.Text = 'Current histogram';
            app.ExportSessionMenu = uimenu(app.ExportMenu);
            app.ExportSessionMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportSessionMenuSelected, true);
            app.ExportSessionMenu.Text = 'Session';
            app.DataMenu = uimenu(app.ExportMenu);
            app.DataMenu.MenuSelectedFcn = createCallbackFcn(app, @DataMenuSelected, true);
            app.DataMenu.Text = 'Data';
            app.AddonMenu = uimenu(app.CellMAPUIFigure);
            app.AddonMenu.Enable = 'off';
            app.AddonMenu.Text = 'Add-on';
            app.PipelineMenu = uimenu(app.AddonMenu);
            app.PipelineMenu.Text = 'Pipeline';
            app.RecordMenu = uimenu(app.PipelineMenu);
            app.RecordMenu.MenuSelectedFcn = createCallbackFcn(app, @RecordMenuSelected, true);
            app.RecordMenu.Text = 'Record';
            app.LoadMenu = uimenu(app.PipelineMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.LoadforcecurvesMenu = uimenu(app.AddonMenu);
            app.LoadforcecurvesMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadforcecurvesMenuSelected, true);
            app.LoadforcecurvesMenu.Text = 'Load force curves';
            app.BatchprocessingofcontactpointMenu = uimenu(app.AddonMenu);
            app.BatchprocessingofcontactpointMenu.MenuSelectedFcn = createCallbackFcn(app, @BatchprocessingofcontactpointMenuSelected, true);
            app.BatchprocessingofcontactpointMenu.Text = 'Batch processing of contact point';
            app.FittingfromcontactpointMenu = uimenu(app.AddonMenu);
            app.FittingfromcontactpointMenu.MenuSelectedFcn = createCallbackFcn(app, @FittingfromcontactpointMenuSelected, true);
            app.FittingfromcontactpointMenu.Text = 'Fitting from contact point';
            app.PlotYfXMenu = uimenu(app.AddonMenu);
            app.PlotYfXMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotYfXMenuSelected, true);
            app.PlotYfXMenu.Text = 'Plot Y=f(X)';
            app.CurvatureMenu = uimenu(app.AddonMenu);
            app.CurvatureMenu.MenuSelectedFcn = createCallbackFcn(app, @CurvatureMenuSelected, true);
            app.CurvatureMenu.Text = 'Curvature';
            app.GeometryThresholdcurveMenu = uimenu(app.AddonMenu);
            app.GeometryThresholdcurveMenu.MenuSelectedFcn = createCallbackFcn(app, @GeometryThresholdcurveMenuSelected, true);
            app.GeometryThresholdcurveMenu.Visible = 'off';
            app.GeometryThresholdcurveMenu.Enable = 'off';
            app.GeometryThresholdcurveMenu.Text = 'Geometry-Threshold curve';
            app.MenuHelp = uimenu(app.CellMAPUIFigure);
            app.MenuHelp.Enable = 'off';
            app.MenuHelp.Text = 'Help';
            app.DescriptionMenu = uimenu(app.MenuHelp);
            app.DescriptionMenu.MenuSelectedFcn = createCallbackFcn(app, @DescriptionMenuSelected, true);
            app.DescriptionMenu.Text = 'Description';
            app.AboutMenu = uimenu(app.CellMAPUIFigure);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.GridLayout2 = uigridlayout(app.CellMAPUIFigure);
            app.MappingPanel = uipanel(app.GridLayout2);
            app.MappingPanel.Title = 'Mapping';
            app.GridLayout3 = uigridlayout(app.MappingPanel);
            app.UIAxesMap = uiaxes(app.GridLayout3);
            app.UIAxesMap.XTick = [];
            app.UIAxesMap.YTick = [];
            app.DatacursorCheckBox = uicheckbox(app.GridLayout3);
            app.DatacursorCheckBox.ValueChangedFcn = createCallbackFcn(app, @DatacursorCheckBoxValueChanged, true);
            app.DatacursorCheckBox.Text = 'Data cursor';
            app.EditFieldCoordY = uieditfield(app.GridLayout3, 'numeric');
            app.EditFieldCoordY.Editable = 'off';
            app.EditFieldCoordY.Visible = 'off';
            app.EditFieldCoordX = uieditfield(app.GridLayout3, 'numeric');
            app.EditFieldCoordX.Editable = 'off';
            app.EditFieldCoordX.Visible = 'off';
            app.SliderCursorX = uislider(app.GridLayout3);
            app.SliderCursorX.Limits = [1 100];
            app.SliderCursorX.MajorTicks = [];
            app.SliderCursorX.MajorTickLabels = {};
            app.SliderCursorX.ValueChangedFcn = createCallbackFcn(app, @SliderCursorXValueChanged, true);
            app.SliderCursorX.MinorTicks = [1 4 7 10 13 16 19 22 25 28 31 34 37 40 43 46 49 52 55 58 61 64 67 70 73 76 79 82 85 88 91 94 97 100];
            app.SliderCursorX.Visible = 'off';
            app.SliderCursorX.Value = 1;
            app.FixedCheckBoxMap = uicheckbox(app.GridLayout3);
            app.FixedCheckBoxMap.Text = 'Hold color scale';
            app.EditFieldMapHigh = uieditfield(app.GridLayout3, 'numeric');
            app.EditFieldMapHigh.ValueChangedFcn = createCallbackFcn(app, @EditFieldMapHighValueChanged, true);
            app.EditFieldMapHigh.Value = 20;
            app.unitLabel_9 = uilabel(app.GridLayout3);
            app.unitLabel_9.Text = 'unit';
            app.EditFieldMapLow = uieditfield(app.GridLayout3, 'numeric');
            app.EditFieldMapLow.ValueChangedFcn = createCallbackFcn(app, @EditFieldMapLowValueChanged, true);
            app.unitLabel_12 = uilabel(app.GridLayout3);
            app.unitLabel_12.Visible = 'off';
            app.unitLabel_12.Text = 'unit';
            app.EditFieldCursor = uieditfield(app.GridLayout3, 'text');
            app.EditFieldCursor.Editable = 'off';
            app.EditFieldCursor.Visible = 'off';
            app.SliderCursorY = uislider(app.GridLayout3);
            app.SliderCursorY.Limits = [1 100];
            app.SliderCursorY.MajorTicks = [];
            app.SliderCursorY.MajorTickLabels = {};
            app.SliderCursorY.ValueChangedFcn = createCallbackFcn(app, @SliderCursorYValueChanged, true);
            app.SliderCursorY.MinorTicks = [1 4 7 10 13 16 19 22 25 28 31 34 37 40 43 46 49 52 55 58 61 64 67 70 73 76 79 82 85 88 91 94 97 100];
            app.SliderCursorY.Visible = 'off';
            app.SliderCursorY.Value = 1;
            app.ImageKeyboard = uiimage(app.GridLayout3);
            app.ImageKeyboard.Visible = 'off';
            app.ImageKeyboard.ImageSource = fullfile(pathToMLAPP, 'resources', 'keyboard.png');
            app.ForcecurveCheckBox = uicheckbox(app.GridLayout3);
            app.ForcecurveCheckBox.ValueChangedFcn = createCallbackFcn(app, @ForcecurveCheckBoxValueChanged, true);
            app.ForcecurveCheckBox.Visible = 'off';
            app.ForcecurveCheckBox.Text = 'Force curve';
            app.OperationsPanel = uipanel(app.GridLayout2);
            app.OperationsPanel.Title = 'Operations';
            app.TabGroup = uitabgroup(app.OperationsPanel);
            app.FlatteningTab = uitab(app.TabGroup);
            app.FlatteningTab.Title = 'Flattening';
            app.ButtonDoitLeveling = uibutton(app.FlatteningTab, 'push');
            app.ButtonDoitLeveling.ButtonPushedFcn = createCallbackFcn(app, @ButtonDoitLevelingPushed, true);
            app.ButtonDoitLeveling.Text = 'Do it';
            app.ThelevelingisdonefromtheimageshownhereLabel = uilabel(app.FlatteningTab);
            app.ThelevelingisdonefromtheimageshownhereLabel.Text = {'Correct the sample surface tilt.'; 'The flattening is done from the image shown on the right.'; 'A height map should be selected.'};
            app.ButtonROILeveling = uibutton(app.FlatteningTab, 'push');
            app.ButtonROILeveling.ButtonPushedFcn = createCallbackFcn(app, @ButtonROILevelingPushed, true);
            app.ButtonROILeveling.Visible = 'off';
            app.ButtonROILeveling.Text = 'Select a ROI to correct leveling';
            app.ThresholdingTab = uitab(app.TabGroup);
            app.ThresholdingTab.Title = 'Thresholding';
            app.UpperEditFieldLabel = uilabel(app.ThresholdingTab);
            app.UpperEditFieldLabel.Text = 'Upper';
            app.EditFieldUpThres = uieditfield(app.ThresholdingTab, 'numeric');
            app.EditFieldUpThres.Value = 1;
            app.ButtonDoitThres = uibutton(app.ThresholdingTab, 'push');
            app.ButtonDoitThres.ButtonPushedFcn = createCallbackFcn(app, @ButtonDoitThresPushed, true);
            app.ButtonDoitThres.Text = 'Do it';
            app.TheflatteningisdonefromtheimageshownhereLabel_2 = uilabel(app.ThresholdingTab);
            app.TheflatteningisdonefromtheimageshownhereLabel_2.Text = {'Apply a threshold.'; 'The threshold uses the image shown on the left as a reference.'; 'All maps are affected by threshold.'};
            app.unitLabel_10 = uilabel(app.ThresholdingTab);
            app.unitLabel_10.Text = 'unit';
            app.LowerLabel = uilabel(app.ThresholdingTab);
            app.LowerLabel.Text = 'Lower';
            app.EditFieldLowThres = uieditfield(app.ThresholdingTab, 'numeric');
            app.CleaningTab = uitab(app.TabGroup);
            app.CleaningTab.Title = 'Cleaning';
            app.NumberofpixelsEditFieldLabel = uilabel(app.CleaningTab);
            app.NumberofpixelsEditFieldLabel.Text = 'Number of pixels';
            app.EditFieldNumPix = uieditfield(app.CleaningTab, 'numeric');
            app.EditFieldNumPix.Value = 50;
            app.ButtonDoitCleaning = uibutton(app.CleaningTab, 'push');
            app.ButtonDoitCleaning.ButtonPushedFcn = createCallbackFcn(app, @ButtonDoitCleaningPushed, true);
            app.ButtonDoitCleaning.Text = 'Do it';
            app.TheflatteningisdonefromtheimageshownhereLabel_3 = uilabel(app.CleaningTab);
            app.TheflatteningisdonefromtheimageshownhereLabel_3.Text = {'Remove isolated points.'; 'Threshold is required before.'; 'Cleaning can be applied indifferently of the selected map.'};
            app.PeaksmoothingTab = uitab(app.TabGroup);
            app.PeaksmoothingTab.Title = 'Peak smoothing';
            app.TheflatteningisdonefromtheimageshownhereLabel_4 = uilabel(app.PeaksmoothingTab);
            app.TheflatteningisdonefromtheimageshownhereLabel_4.Text = {'Smooth isolated peaks and replace by the average value in the surrounding.'; 'The height map should be selected.'};
            app.ButtonDoitPeakremoving = uibutton(app.PeaksmoothingTab, 'push');
            app.ButtonDoitPeakremoving.ButtonPushedFcn = createCallbackFcn(app, @ButtonDoitPeakremovingPushed, true);
            app.ButtonDoitPeakremoving.Text = 'Do it';
            app.GradientstepLabel = uilabel(app.PeaksmoothingTab);
            app.GradientstepLabel.Text = 'Gradient step';
            app.EditFieldGradientstep = uieditfield(app.PeaksmoothingTab, 'numeric');
            app.EditFieldGradientstep.Value = 1;
            app.unitLabel_11 = uilabel(app.PeaksmoothingTab);
            app.unitLabel_11.Text = 'unit';
            app.HoleremovingTab = uitab(app.TabGroup);
            app.HoleremovingTab.Title = 'Hole removing';
            app.ButtonDoitHoleremoving = uibutton(app.HoleremovingTab, 'push');
            app.ButtonDoitHoleremoving.ButtonPushedFcn = createCallbackFcn(app, @ButtonDoitHoleremovingPushed, true);
            app.ButtonDoitHoleremoving.Text = 'Do it';
            app.TheflatteningisdonefromtheimageshownhereLabel_7 = uilabel(app.HoleremovingTab);
            app.TheflatteningisdonefromtheimageshownhereLabel_7.Text = {'Remove isolated holes, that appeared because of the successive filters. They are replaced by the average value in the surrounding.'; 'Only done in the selected map.'};
            app.CenteringTab = uitab(app.TabGroup);
            app.CenteringTab.Title = 'Centering';
            app.ButtonDoitCentering = uibutton(app.CenteringTab, 'push');
            app.ButtonDoitCentering.ButtonPushedFcn = createCallbackFcn(app, @ButtonDoitCenteringPushed, true);
            app.ButtonDoitCentering.Text = 'Do it';
            app.TheflatteningisdonefromtheimageshownhereLabel_5 = uilabel(app.CenteringTab);
            app.TheflatteningisdonefromtheimageshownhereLabel_5.Text = {'Center all images with respect of their geometrical center.'; 'Threshold is required before.'};
            app.MakeMontageTab = uitab(app.TabGroup);
            app.MakeMontageTab.Title = 'Make Montage';
            app.DoitButton = uibutton(app.MakeMontageTab, 'push');
            app.DoitButton.ButtonPushedFcn = createCallbackFcn(app, @DoitButtonPushed, true);
            app.DoitButton.Text = 'Do it';
            app.TheflatteningisdonefromtheimageshownhereLabel_8 = uilabel(app.MakeMontageTab);
            app.TheflatteningisdonefromtheimageshownhereLabel_8.Text = 'Make a montage of several  user-defined cells as one.';
            app.TopographyTab = uitab(app.TabGroup);
            app.TopographyTab.Title = 'Topography';
            app.DropDownVariable_2 = uidropdown(app.TopographyTab);
            app.DropDownVariable_2.Items = {};
            app.DropDownVariable_2.Value = {};
            app.TheflatteningisdonefromtheimageshownhereLabel_6 = uilabel(app.TopographyTab);
            app.TheflatteningisdonefromtheimageshownhereLabel_6.Text = {'The color code is the "Type of data".'; ''; 'Height is:'};
            app.ButtonDoitTopography = uibutton(app.TopographyTab, 'push');
            app.ButtonDoitTopography.ButtonPushedFcn = createCallbackFcn(app, @ButtonDoitTopographyPushed, true);
            app.ButtonDoitTopography.Text = 'Do it';
            app.AveragingTab = uitab(app.TabGroup);
            app.AveragingTab.Title = 'Averaging';
            app.ButtonDoitAveraging = uibutton(app.AveragingTab, 'push');
            app.ButtonDoitAveraging.ButtonPushedFcn = createCallbackFcn(app, @ButtonDoitAveragingPushed, true);
            app.ButtonDoitAveraging.Text = 'Do it';
            app.AveragecellsintooneLabel = uilabel(app.AveragingTab);
            app.AveragecellsintooneLabel.Text = 'Average cells into one';
            app.DataROI = uitab(app.TabGroup);
            app.DataROI.Title = 'Data from ROI';
            app.ButtonROILabel = uilabel(app.DataROI);
            app.ButtonROILabel.Text = 'Perform data statistics from a selected ROI. Press Enter to finish.';
            app.ButtonROIManager = uibutton(app.DataROI, 'push');
            app.ButtonROIManager.ButtonPushedFcn = createCallbackFcn(app, @ButtonROIManagerPushed, true);
            app.ButtonROIManager.Text = 'Open ROI Manager';
            app.PanelGeometry = uipanel(app.GridLayout2);
            app.PanelGeometry.Title = 'Geometry';
            app.GridLayout5 = uigridlayout(app.PanelGeometry);
            app.IfheightinmLabel = uilabel(app.GridLayout5);
            app.IfheightinmLabel.Text = 'If height in µm';
            app.mLabelTopArea = uilabel(app.GridLayout5);
            app.mLabelTopArea.Text = 'µm²';
            app.mLabelVolume = uilabel(app.GridLayout5);
            app.mLabelVolume.Text = 'µm^{3}';
            app.mLabelProjectedSurface = uilabel(app.GridLayout5);
            app.mLabelProjectedSurface.Text = 'µm²';
            app.mLabel = uilabel(app.GridLayout5);
            app.mLabel.Text = 'µm';
            app.PixelsizeEditField = uieditfield(app.GridLayout5, 'numeric');
            app.PixelsizeEditField.Limits = [0 Inf];
            app.PixelsizeEditField.ValueChangedFcn = createCallbackFcn(app, @PixelsizeEditFieldValueChanged, true);
            app.PixelsizeEditField.Value = 1;
            app.PixelsizeEditFieldLabel = uilabel(app.GridLayout5);
            app.PixelsizeEditFieldLabel.Text = 'Pixel size';
            app.TopareaEditField = uieditfield(app.GridLayout5, 'text');
            app.TopareaEditField.Editable = 'off';
            app.TopareaEditFieldLabel = uilabel(app.GridLayout5);
            app.TopareaEditFieldLabel.Text = 'Top area';
            app.ProjectedsurfaceEditField = uieditfield(app.GridLayout5, 'text');
            app.ProjectedsurfaceEditField.Editable = 'off';
            app.ProjectedsurfaceEditFieldLabel = uilabel(app.GridLayout5);
            app.ProjectedsurfaceEditFieldLabel.Text = 'Projected surface';
            app.VolumeEditField = uieditfield(app.GridLayout5, 'text');
            app.VolumeEditField.Editable = 'off';
            app.VolumeEditFieldLabel = uilabel(app.GridLayout5);
            app.VolumeEditFieldLabel.Text = 'Volume';
            app.PanelHisto = uipanel(app.GridLayout2);
            app.PanelHisto.Title = 'Statistics';
            app.GridLayout6 = uigridlayout(app.PanelHisto);
            app.unitLabel = uilabel(app.GridLayout6);
            app.unitLabel.Text = 'unit';
            app.GeometricmeanEditField = uieditfield(app.GridLayout6, 'text');
            app.GeometricmeanEditField.Editable = 'off';
            app.GeometricmeanEditFieldLabel = uilabel(app.GridLayout6);
            app.GeometricmeanEditFieldLabel.Text = 'Geometric mean';
            app.ArithmeticmeanEditField = uieditfield(app.GridLayout6, 'text');
            app.ArithmeticmeanEditField.Editable = 'off';
            app.ArithmeticmeanEditFieldLabel = uilabel(app.GridLayout6);
            app.ArithmeticmeanEditFieldLabel.Text = 'Arithmetic mean';
            app.StandarddeviationEditField = uieditfield(app.GridLayout6, 'text');
            app.StandarddeviationEditField.Editable = 'off';
            app.StandarddeviationEditFieldLabel = uilabel(app.GridLayout6);
            app.StandarddeviationEditFieldLabel.Text = 'Standard deviation';
            app.StandarderrorofthemeanEditField = uieditfield(app.GridLayout6, 'text');
            app.StandarderrorofthemeanEditField.Editable = 'off';
            app.StandarderrorofthemeanEditFieldLabel = uilabel(app.GridLayout6);
            app.StandarderrorofthemeanEditFieldLabel.Text = 'Standard error of the mean';
            app.GeometricstandarddeviationEditField = uieditfield(app.GridLayout6, 'text');
            app.GeometricstandarddeviationEditField.Editable = 'off';
            app.GeometricstandarddeviationEditFieldLabel = uilabel(app.GridLayout6);
            app.GeometricstandarddeviationEditFieldLabel.Text = 'Geometric standard deviation';
            app.MinimumEditField = uieditfield(app.GridLayout6, 'text');
            app.MinimumEditField.Editable = 'off';
            app.MinimumEditFieldLabel = uilabel(app.GridLayout6);
            app.MinimumEditFieldLabel.Text = 'Minimum';
            app.DatapointsEditFieldLabel = uilabel(app.GridLayout6);
            app.DatapointsEditFieldLabel.Text = 'Data points';
            app.DatapointsEditField = uieditfield(app.GridLayout6, 'text');
            app.DatapointsEditField.Editable = 'off';
            app.QuantileEditField = uieditfield(app.GridLayout6, 'text');
            app.QuantileEditField.Editable = 'off';
            app.MedianEditFieldLabel = uilabel(app.GridLayout6);
            app.MedianEditFieldLabel.Text = 'Median';
            app.MaximumEditField = uieditfield(app.GridLayout6, 'text');
            app.MaximumEditField.Editable = 'off';
            app.MaximumEditFieldLabel = uilabel(app.GridLayout6);
            app.MaximumEditFieldLabel.Text = 'Maximum';
            app.QuantileDropDownLabel = uilabel(app.GridLayout6);
            app.QuantileDropDownLabel.Text = 'Quantile';
            app.QuantileDropDown = uidropdown(app.GridLayout6);
            app.QuantileDropDown.Items = {'0.1 (1st decile)', '0.25 (1st quartile)', '0.5 (median)', '0.75 (3rd quartile)', '0.9 (9th decile)'};
            app.QuantileDropDown.ItemsData = [1 2 3 4 5];
            app.QuantileDropDown.ValueChangedFcn = createCallbackFcn(app, @QuantileDropDownValueChanged, true);
            app.QuantileDropDown.Value = 3;
            app.PanelHistogram = uipanel(app.GridLayout2);
            app.PanelHistogram.Title = 'Distribution';
            app.GridLayout4 = uigridlayout(app.PanelHistogram);
            app.UIAxesHisto = uiaxes(app.GridLayout4);
            app.UIAxesHisto.XTickLabelRotationMode = 'manual';
            app.UIAxesHisto.YTickLabelRotationMode = 'manual';
            app.UIAxesHisto.ZTickLabelRotationMode = 'manual';
            app.EditFieldHistoHigh = uieditfield(app.GridLayout4, 'numeric');
            app.EditFieldHistoHigh.ValueChangedFcn = createCallbackFcn(app, @EditFieldHistoHighValueChanged, true);
            app.EditFieldHistoHigh.Value = 20;
            app.EditFieldHistoLow = uieditfield(app.GridLayout4, 'numeric');
            app.EditFieldHistoLow.ValueChangedFcn = createCallbackFcn(app, @EditFieldHistoLowValueChanged, true);
            app.FixedCheckBoxHisto = uicheckbox(app.GridLayout4);
            app.FixedCheckBoxHisto.Text = 'Hold histogram limits';
            app.CheckBoxHisto = uicheckbox(app.GridLayout4);
            app.CheckBoxHisto.ValueChangedFcn = createCallbackFcn(app, @CheckBoxHistoValueChanged, true);
            app.CheckBoxHisto.Text = 'Find on map';
            app.HistogramFitterButton = uibutton(app.GridLayout4, 'push');
            app.HistogramFitterButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramFitterButtonPushed, true);
            app.HistogramFitterButton.Text = 'Histogram Fitter';
            app.FittingparametersaredisplayedinthecommandwindowLabel = uilabel(app.GridLayout4);
            app.FittingparametersaredisplayedinthecommandwindowLabel.Text = 'Fitting parameters are displayed in the command window';
            app.unitLabel_8 = uilabel(app.GridLayout4);
            app.unitLabel_8.Text = 'unit';
            app.BinwidthLabel = uilabel(app.GridLayout4);
            app.BinwidthLabel.Text = 'Bin width';
            app.EditFieldBinWidth = uieditfield(app.GridLayout4, 'numeric');
            app.EditFieldBinWidth.Limits = [0 Inf];
            app.EditFieldBinWidth.ValueChangedFcn = createCallbackFcn(app, @EditFieldBinWidthValueChanged, true);
            app.EditFieldBinWidth.Value = 0.25;
            app.NormalizationDropDownLabel = uilabel(app.GridLayout4);
            app.NormalizationDropDownLabel.Text = 'Normalization';
            app.DropDownNormalization = uidropdown(app.GridLayout4);
            app.DropDownNormalization.Items = {'count', 'countdensity', 'cumcount', 'probability', 'pdf', 'cdf'};
            app.DropDownNormalization.ValueChangedFcn = createCallbackFcn(app, @DropDownNormalizationValueChanged, true);
            app.DropDownNormalization.Value = 'count';
            app.SliderHisto = uislider(app.GridLayout4);
            app.SliderHisto.Limits = [1 10];
            app.SliderHisto.MajorTicks = [];
            app.SliderHisto.MajorTickLabels = {''};
            app.SliderHisto.ValueChangedFcn = createCallbackFcn(app, @SliderHistoValueChanged, true);
            app.SliderHisto.MinorTicks = [1 1.2 1.4 1.6 1.8 2 2.2 2.4 2.6 2.8 3 3.2 3.4 3.6 3.8 4 4.2 4.4 4.6 4.8 5 5.2 5.4 5.6 5.8 6 6.2 6.4 6.6 6.8 7 7.2 7.4 7.6 7.8 8 8.2 8.4 8.6 8.8 9 9.2 9.4 9.6 9.8 10];
            app.SliderHisto.Visible = 'off';
            app.SliderHisto.Value = 1;
            app.ImageMouse = uiimage(app.GridLayout4);
            app.ImageMouse.Visible = 'off';
            app.ImageMouse.ImageSource = fullfile(pathToMLAPP, 'resources', 'mouse.png');
            app.DistributionFitterfromMatlabButton = uibutton(app.GridLayout4, 'push');
            app.DistributionFitterfromMatlabButton.ButtonPushedFcn = createCallbackFcn(app, @DistributionFitterfromMatlabButtonPushed, true);
            app.DistributionFitterfromMatlabButton.Text = 'Distribution Fitter (from Matlab)';
            app.ParametersPanel = uipanel(app.GridLayout2);
            app.ParametersPanel.Title = 'Parameters';
            app.GridLayout = uigridlayout(app.ParametersPanel);
            app.ConvertButton = uibutton(app.GridLayout, 'push');
            app.ConvertButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertButtonPushed, true);
            app.ConvertButton.Text = 'Convert';
            app.UnitEditField = uieditfield(app.GridLayout, 'text');
            app.UnitEditField.Editable = 'off';
            app.UnitLabel = uilabel(app.GridLayout);
            app.UnitLabel.Text = 'Unit:';
            app.TypeofdataLabel = uilabel(app.GridLayout);
            app.TypeofdataLabel.Text = 'Type of data';
            app.DropDownCell = uidropdown(app.GridLayout);
            app.DropDownCell.Items = {};
            app.DropDownCell.Visible = 'off';
            app.DropDownCell.Value = {};
            app.ProcessLabel = uilabel(app.GridLayout);
            app.ProcessLabel.Text = 'Process';
            app.DropDownVariable = uidropdown(app.GridLayout);
            app.DropDownVariable.Items = {};
            app.DropDownVariable.ValueChangedFcn = createCallbackFcn(app, @DropDownVariableValueChanged, true);
            app.DropDownVariable.Value = {};
            app.DropDownFilter = uidropdown(app.GridLayout);
            app.DropDownFilter.Items = {};
            app.DropDownFilter.ValueChangedFcn = createCallbackFcn(app, @DropDownFilterValueChanged, true);
            app.DropDownFilter.Value = {};
            app.DeleteButtonCell = uibutton(app.GridLayout, 'push');
            app.DeleteButtonCell.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonCellPushed, true);
            app.DeleteButtonCell.Text = 'Delete';
            app.DeleteButtoVar = uibutton(app.GridLayout, 'push');
            app.DeleteButtoVar.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtoVarButtonPushed, true);
            app.DeleteButtoVar.Text = 'Delete';
            app.DeleteButtonFilter = uibutton(app.GridLayout, 'push');
            app.DeleteButtonFilter.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonFilterPushed, true);
            app.DeleteButtonFilter.Text = 'Delete';
            app.CellnSpinner = uispinner(app.GridLayout);
            app.CellnSpinner.Limits = [1 1.1];
            app.CellnSpinner.ValueChangedFcn = createCallbackFcn(app, @CellnSpinnerValueChanged, true);
            app.CellnSpinner.Value = 1;
            app.CellnSpinnerLabel = uilabel(app.GridLayout);
            app.CellnSpinnerLabel.Text = 'Cell n°';
            app.N_cellEditFieldLabel = uilabel(app.GridLayout2);
            app.N_cellEditFieldLabel.Interpreter = 'tex';
            app.N_cellEditFieldLabel.Text = 'N_{cell} =';
            app.N_cellEditField = uieditfield(app.GridLayout2, 'numeric');
            app.N_cellEditField.Editable = 'off';
            app.N_cellEditField.Value = 1;
            app.ROILabel = uilabel(app.CellMAPUIFigure);
            app.ROILabel.Visible = 'off';
            app.ROILabel.Text = {'Draw: Click and drag to draw the line (needs to be slow enough)'; ''; 'Cancel:	Press Esc.'; ''; 'Finish: Press Enter'; ''; 'Reshape: Position pointer over a waypoint and then click and drag.'; ''; 'Add a waypoint: Position the pointer on an edge of the ROI, right-click, and select Add Waypoint.'; 'You can also position the pointer on an edge of the ROI and double-click.'; ''; 'Remove a waypoint: Position the pointer on a waypoint, right-click, and select Remove Waypoint.'; ''; 'Move the ROI:	Position the pointer over the ROI. The pointer changes to the fleur shape. Click and drag to move the ROI.'; ''; 'Delete an ROI: Position the pointer on the ROI (not on a vertex), right-click, and select Delete Freehand from the context menu.'};
            app.Image = uiimage(app.CellMAPUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'resources', 'Topography_crop.png');
            app.Copyright = uilabel(app.CellMAPUIFigure);
            app.Copyright.Interpreter = 'html';
            app.Copyright.Text = {'Version: 1.4 (2026-09-24)'; 'Main software developer: Antoine Allard'; 'Contact: antoine.allard@u-bordeaux.fr'; 'License: CC BY-NC-SA 4.0'; '<a href="https://link.springer.com/article/10.1186/s12859-025-06060-0">Allard, A., Liboz, M., Crépin, R. et al. CellMAP: an open-source software tool to batch-process cell topography and stiffness maps collected with an atomic'; 'force microscope. BMC Bioinformatics 26, 38 (2025). https://doi.org/10.1186/s12859-025-06060-0</a>'; 'Any restrictions to use by non-academics: You may not use the material for commercial purposes.'};
            app.Image2 = uiimage(app.CellMAPUIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'resources', 'copyright.png');
            % Show the figure after all components are created
            app.CellMAPUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CellMAP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CellMAPUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CellMAPUIFigure)
        end
    end
end