classdef ContactPointApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Label               matlab.ui.control.Label
        ButtonPreviewFit    matlab.ui.control.Button
        BatchProcessButton  matlab.ui.control.Button
        Spanofthesmoothingdefault10Label  matlab.ui.control.Label
        Span                matlab.ui.control.Spinner
        Widthofthewindowforthelocalregressiondefault10datapointsLabel  matlab.ui.control.Label
        Window              matlab.ui.control.NumericEditField
        Multiplierforthefirstalarmthresholddefault2noiseLabel  matlab.ui.control.Label
        Mul1                matlab.ui.control.NumericEditField
        Multiplierforthesecondalarmthresholddefault10noiseLabel  matlab.ui.control.Label
        Mul2                matlab.ui.control.NumericEditField
        Multiplierforthesecondalarmthresholddefault10noiseLabel_2  matlab.ui.control.Label
        MaxNoise            matlab.ui.control.NumericEditField
        MinNoise            matlab.ui.control.NumericEditField
        SwitchLabel         matlab.ui.control.Label
        Delta               matlab.ui.control.Switch
        Loess               matlab.ui.control.Switch
        Widthofthewindowforthelocalregressiondefault50datapointsLabel_2  matlab.ui.control.Label
        Lagdiff             matlab.ui.control.NumericEditField
    end


    properties (Access = private) 
        CallingApp   % Main app object
        CallingForceApp
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, forceapp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            app.CallingForceApp = forceapp;
        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)
            % Delete the dialog box 
            delete(app)        
        end

        % Button pushed function: ButtonPreviewFit
        function ButtonPreviewFitPushed(app, event)
            if strcmp(app.Loess.Value,'None')
                app.CallingApp.NewContactPoint.Loess = false;
            else
                app.CallingApp.NewContactPoint.Loess = true;
            end
            
            app.CallingApp.NewContactPoint.Span = app.Span.Value/100;
            
            if strcmp(app.Delta.Value,'Slope')
                app.CallingApp.NewContactPoint.Delta = false;
            else
                app.CallingApp.NewContactPoint.Delta = true;
            end
            
            app.CallingApp.NewContactPoint.Window = app.Window.Value;
            app.CallingApp.NewContactPoint.Lagdiff = app.Lagdiff.Value;
            app.CallingApp.NewContactPoint.MinNoise = app.MinNoise.Value/100;
            app.CallingApp.NewContactPoint.MaxNoise = app.MaxNoise.Value/100;
            app.CallingApp.NewContactPoint.Mul1 = app.Mul1.Value;
            app.CallingApp.NewContactPoint.Mul2 = app.Mul2.Value;
            
            [CP,newforce] = app.CallingApp.findContactPoint;
            app.CallingForceApp.displayContactPoint(CP,newforce);
            
            app.BatchProcessButton.Enable = true;
        end

        % Button pushed function: BatchProcessButton
        function BatchProcessButtonPushed(app, event)
            app.CallingApp.doBatchContactPoint;
            DialogAppCloseRequest(app)
        end

        % Value changed function: Window
        function WindowValueChanged(app, event)
            value = app.Window.Value;
            app.Window.Value = round(value);
        end

        % Value changed function: Lagdiff
        function LagdiffValueChanged(app, event)
            value = app.Lagdiff.Value;
            app.Lagdiff.Value = round(value);
        end

        % Value changed function: MaxNoise
        function MaxNoiseValueChanged(app, event)
            valueMin = app.MinNoise.Value;
            valueMax = app.MaxNoise.Value;
            if valueMin >= valueMax
                app.MaxNoise.Value = min(app.MinNoise.Value + 1,100);
            end
        end

        % Value changed function: MinNoise
        function MinNoiseValueChanged(app, event)
            valueMin = app.MinNoise.Value;
            valueMax = app.MaxNoise.Value;
            if valueMin >= valueMax
                app.MinNoise.Value = max(app.MaxNoise.Value - 1,0);
            end
        end

        % Value changed function: Loess
        function LoessValueChanged(app, event)
            value = app.Loess.Value;
            if strcmp(value,'None')
                app.Span.Enable = false;
            else
                app.Span.Enable = true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Contact point: batch processing';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'This function enables the user to find the contact point from Force-Distance curves following the local regression and two thresholds methods described in:'; 'Benitez et .al, 2013, Microscopy reserach and technique, 76 (8) pp. 870-876.'};
            app.ButtonPreviewFit = uibutton(app.UIFigure, 'push');
            app.ButtonPreviewFit.ButtonPushedFcn = createCallbackFcn(app, @ButtonPreviewFitPushed, true);
            app.ButtonPreviewFit.Text = 'Preview';
            app.BatchProcessButton = uibutton(app.UIFigure, 'push');
            app.BatchProcessButton.ButtonPushedFcn = createCallbackFcn(app, @BatchProcessButtonPushed, true);
            app.BatchProcessButton.Enable = 'off';
            app.BatchProcessButton.Text = 'Batch Process';
            app.Spanofthesmoothingdefault10Label = uilabel(app.UIFigure);
            app.Spanofthesmoothingdefault10Label.Text = 'Span of the smoothing (default: 10%)';
            app.Span = uispinner(app.UIFigure);
            app.Span.Limits = [1 100];
            app.Span.Value = 10;
            app.Widthofthewindowforthelocalregressiondefault10datapointsLabel = uilabel(app.UIFigure);
            app.Widthofthewindowforthelocalregressiondefault10datapointsLabel.Text = 'Width of the window for the local regression (default: 10 data points)';
            app.Window = uieditfield(app.UIFigure, 'numeric');
            app.Window.Limits = [1 Inf];
            app.Window.ValueChangedFcn = createCallbackFcn(app, @WindowValueChanged, true);
            app.Window.Value = 10;
            app.Multiplierforthefirstalarmthresholddefault2noiseLabel = uilabel(app.UIFigure);
            app.Multiplierforthefirstalarmthresholddefault2noiseLabel.Text = 'Multiplier for the first alarm threshold (default: 2 * noise)';
            app.Mul1 = uieditfield(app.UIFigure, 'numeric');
            app.Mul1.Limits = [0 Inf];
            app.Mul1.Value = 2;
            app.Multiplierforthesecondalarmthresholddefault10noiseLabel = uilabel(app.UIFigure);
            app.Multiplierforthesecondalarmthresholddefault10noiseLabel.Text = 'Multiplier for the second alarm threshold (default: 10 * noise)';
            app.Mul2 = uieditfield(app.UIFigure, 'numeric');
            app.Mul2.Limits = [0 Inf];
            app.Mul2.Value = 10;
            app.Multiplierforthesecondalarmthresholddefault10noiseLabel_2 = uilabel(app.UIFigure);
            app.Multiplierforthesecondalarmthresholddefault10noiseLabel_2.Text = 'Range where the noise (std of the force) is calculated (default: from 10 to 20% of the total number of points)';
            app.MaxNoise = uieditfield(app.UIFigure, 'numeric');
            app.MaxNoise.Limits = [0 100];
            app.MaxNoise.ValueChangedFcn = createCallbackFcn(app, @MaxNoiseValueChanged, true);
            app.MaxNoise.Value = 20;
            app.MinNoise = uieditfield(app.UIFigure, 'numeric');
            app.MinNoise.Limits = [0 100];
            app.MinNoise.ValueChangedFcn = createCallbackFcn(app, @MinNoiseValueChanged, true);
            app.MinNoise.Value = 10;
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = {'If DELTA, then the statistic for determining the contact point is'; 'the differences between two consecutive values of the slope of the local regression line. If SLOPE (default) then the slope itself is used.'};
            app.Delta = uiswitch(app.UIFigure, 'slider');
            app.Delta.Items = {'Slope', 'Delta'};
            app.Delta.Value = 'Slope';
            app.Loess = uiswitch(app.UIFigure, 'slider');
            app.Loess.Items = {'None', 'Loess smoothing'};
            app.Loess.ValueChangedFcn = createCallbackFcn(app, @LoessValueChanged, true);
            app.Loess.Value = 'Loess smoothing';
            app.Widthofthewindowforthelocalregressiondefault50datapointsLabel_2 = uilabel(app.UIFigure);
            app.Widthofthewindowforthelocalregressiondefault50datapointsLabel_2.Text = 'Lag for estimating the differences in Delta (or Slope) signal. By default it takes the same value as the window width.';
            app.Lagdiff = uieditfield(app.UIFigure, 'numeric');
            app.Lagdiff.Limits = [1 Inf];
            app.Lagdiff.ValueChangedFcn = createCallbackFcn(app, @LagdiffValueChanged, true);
            app.Lagdiff.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ContactPointApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end