classdef FittingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ButtonUpdateTables           matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        FxEditFieldLabel             matlab.ui.control.Label
        FxEditField                  matlab.ui.control.EditField
        SelectyourvariablesListBoxLabel  matlab.ui.control.Label
        SelectyourvariablesListBox   matlab.ui.control.ListBox
        UIParameterTable             matlab.ui.control.Table
        UIVariableTable              matlab.ui.control.Table
        ButtonPreviewFit             matlab.ui.control.Button
        RUNButton                    matlab.ui.control.Button
        PercentofpointstofitSpinnerLabel  matlab.ui.control.Label
        PercentofpointstofitSpinner  matlab.ui.control.Spinner
    end


    properties (Access = private) 
        CallingApp   % Main app object
        CallingForceApp
        deflection
        force
        contactpoint
    end
    
    properties (Access = public)
        RunFitting = false;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, forceapp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            app.CallingForceApp = forceapp;
        end

        % Button pushed function: ButtonUpdateTables
        function ButtonUpdateTablesPushed(app, event)
            expression = app.FxEditField.Value;
            myfit = fittype(expression);
            variable = coeffnames(myfit);
            app.SelectyourvariablesListBox.Items = variable;
            app.ButtonPreviewFit.Enable = 'on';
            app.SelectyourvariablesListBoxValueChanged
        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)
%             app.CallingApp.Fitting.Enable = false;
            % Delete the dialog box 
            delete(app)        
        end

        % Value changed function: SelectyourvariablesListBox
        function SelectyourvariablesListBoxValueChanged(app, event)
            list = app.SelectyourvariablesListBox.Items;
            valueVariable = app.SelectyourvariablesListBox.Value;
            tmp = ismember(list,valueVariable);
            app.UIVariableTable.Data = list(tmp)';
            if ~isempty(app.UIVariableTable.Data)
                app.UIVariableTable.Data(:,3) = {0};
                app.UIVariableTable.Data(:,4) = {-inf};
                app.UIVariableTable.Data(:,5) = {inf};
            end
            app.UIParameterTable.Data = list(~tmp)';
            if ~isempty(app.UIParameterTable.Data)
                app.UIParameterTable.Data(:,2) = {0};
            end
        end

        % Button pushed function: ButtonPreviewFit
        function ButtonPreviewFitPushed(app, event)
            expression = app.FxEditField.Value;
            listCoefficients = app.UIVariableTable.Data(:,1)';
            listProblems = app.UIParameterTable.Data(:,1)';
            StartPoint = cell2mat(app.UIVariableTable.Data(:,3)');
            LowerBound = cell2mat(app.UIVariableTable.Data(:,4)');
            UpperBound = cell2mat(app.UIVariableTable.Data(:,5)');
            Problem = app.UIParameterTable.Data(:,2)';
            myfit = getmyfit(expression,listCoefficients,listProblems);
            percent = app.PercentofpointstofitSpinner.Value;
            [fitted,row,col] = app.CallingApp.doForceFit(myfit,StartPoint,LowerBound,UpperBound,Problem,percent);
            app.CallingForceApp.updateaxes(row,col)
            
            c = coeffvalues(fitted);
            app.UIVariableTable.Data(:,6) = num2cell(c');
            err = confint(fitted);
            err = (err(2,:) - err(1,:))/2;
            app.UIVariableTable.Data(:,7) = num2cell(err');
            app.RUNButton.Enable = 'on';

            app.CallingApp.Fitting.myfit = myfit;
            app.CallingApp.Fitting.unitCoefficients = app.UIVariableTable.Data(:,2);
            app.CallingApp.Fitting.StartPoint = StartPoint;
            app.CallingApp.Fitting.LowerBound = LowerBound;
            app.CallingApp.Fitting.UpperBound = UpperBound;
            app.CallingApp.Fitting.Problem = Problem;
            app.CallingApp.Fitting.Percent = percent;
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            app.CallingApp.doBatchForceFit;
            DialogAppCloseRequest(app)
        end

        % Value changed function: PercentofpointstofitSpinner
        function PercentofpointstofitSpinnerValueChanged(app, event)
            app.ButtonPreviewFitPushed
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Fitting';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.ButtonUpdateTables = uibutton(app.UIFigure, 'push');
            app.ButtonUpdateTables.ButtonPushedFcn = createCallbackFcn(app, @ButtonUpdateTablesPushed, true);
            app.ButtonUpdateTables.Text = 'OK';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Enter the fitting model for the force F [in nN] as a function of the indentation x [in um]';
            app.FxEditFieldLabel = uilabel(app.UIFigure);
            app.FxEditFieldLabel.Text = 'F(x) =';
            app.FxEditField = uieditfield(app.UIFigure, 'text');
            app.FxEditField.Value = '0.8887*E/(1-nu^2)*x^2*tan(a*pi/180)+x*sigma+F0';
            app.SelectyourvariablesListBoxLabel = uilabel(app.UIFigure);
            app.SelectyourvariablesListBoxLabel.Text = 'Select your variable(s)';
            app.SelectyourvariablesListBox = uilistbox(app.UIFigure);
            app.SelectyourvariablesListBox.Items = {};
            app.SelectyourvariablesListBox.Multiselect = 'on';
            app.SelectyourvariablesListBox.ValueChangedFcn = createCallbackFcn(app, @SelectyourvariablesListBoxValueChanged, true);
            app.SelectyourvariablesListBox.Value = {};
            app.UIParameterTable = uitable(app.UIFigure);
            app.UIParameterTable.ColumnName = {'Parameter'; 'Value'};
            app.UIParameterTable.RowName = {};
            app.UIParameterTable.ColumnEditable = [false true];
            app.UIVariableTable = uitable(app.UIFigure);
            app.UIVariableTable.ColumnName = {'Variable'; 'Unit'; 'Start point'; 'Lower bound'; 'Upper bound'; 'Fitting'; 'Error (+/-)'};
            app.UIVariableTable.RowName = {};
            app.UIVariableTable.ColumnEditable = [false true true true true false false];
            app.ButtonPreviewFit = uibutton(app.UIFigure, 'push');
            app.ButtonPreviewFit.ButtonPushedFcn = createCallbackFcn(app, @ButtonPreviewFitPushed, true);
            app.ButtonPreviewFit.Enable = 'off';
            app.ButtonPreviewFit.Text = 'Preview fit';
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Enable = 'off';
            app.RUNButton.Text = 'RUN';
            app.PercentofpointstofitSpinnerLabel = uilabel(app.UIFigure);
            app.PercentofpointstofitSpinnerLabel.Text = 'Percent of points to fit';
            app.PercentofpointstofitSpinner = uispinner(app.UIFigure);
            app.PercentofpointstofitSpinner.Limits = [1 100];
            app.PercentofpointstofitSpinner.ValueChangedFcn = createCallbackFcn(app, @PercentofpointstofitSpinnerValueChanged, true);
            app.PercentofpointstofitSpinner.Value = 100;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FittingApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end