classdef ForceCurveApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DialogApp              matlab.ui.Figure
        ExportFigureButton     matlab.ui.control.Button
        DeletedatapointButton  matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
    end


    properties (Access = private) 
        CallingApp;   % Main app object
    end
    
    properties (Access = public)
        Force
        Height
        row
        col
    end
    
    methods (Access = public)
      
        function updateaxes(app,row,col,fig)
            app.row = row;
            app.col = col;
            CellName = app.CallingApp.DropDownCell.Value;
            jpkSize=[];
            try
                jpkSize=length(app.CallingApp.ForceCurves.(CellName).jpkDir);
            catch
            end
            isforce=[];
            try
                isforce=length(app.CallingApp.ForceCurves.(CellName).force);
            catch
            end
            field = app.CallingApp.ForceCurves.(CellName);
            if ~isempty(jpkSize) && isempty(isforce)
                folder = app.CallingApp.ForceCurves.(CellName).jpkDir.folder;
                name = app.CallingApp.ForceCurves.(CellName).jpkDir.name;
                [~,height,force] = ReadJPK(folder,name,app.row,app.col);
                app.Force = force;
                app.Height = height;
            else
                app.Force = squeeze(field.force(row,col,:));
                app.Height = squeeze(field.height(row,col,:));
            end
            fitted = field.Fitted{row,col};
            condHeight = field.condHeight{row,col};
            if nargin == 3
                if isempty(fitted)
                    plot(app.UIAxes,app.Height,app.Force,'.k')
                else
                    fig = figure('Visible','off');  % you may want to set this to "on" to see what's happening
                    h = plot(fitted,'c-',app.Height,app.Force,'.k',condHeight,'mx');
                    % Copy content of temp axes to app designer
                    cla(app.UIAxes)
                    copyobj(flipud(h), app.UIAxes); 
                    % You'll need to recreate the legend since it can't be independently copied
                    lh = legend(app.UIAxes);
                    lh.Location = 'northwest';
                    % Delete temp figure
                    delete(fig)
                end
                app.UIAxes.XLabel.String = 'Indentation [um]';
                app.UIAxes.YLabel.String ='Force [nN]';
                if ~all(isnan(app.Height))
                    app.UIAxes.XLim = [min(app.Height) max(app.Height)];
                    app.UIAxes.YLim = [min(app.Force) max(app.Force)];
                end
            elseif nargin == 4
                figure(fig)
                if isempty(fitted)
                    plot(app.Height,app.Force,'.k')
                else
                    hold on
                    plot(fitted,'c-',app.Height,app.Force,'.k',condHeight,'mx');
                    lh = legend('on');
                    lh.Location = 'northwest';
                end
                xlabel('Indentation [um]')
                ylabel('Force [nN]');
                if ~all(isnan(app.Height))
                    xlim([min(app.Height) max(app.Height)]);
                    ylim([min(app.Force) max(app.Force)]);
                end
            end
        end
        
        function displayContactPoint(app,CP,newForce)
            app.updateaxes(app.row,app.col);
            hold(app.UIAxes,'on')
            plot(app.UIAxes,[CP CP],...
                [min(app.Force) max(app.Force)],'-r')
            plot(app.UIAxes,app.Height,newForce,'-m')
            MinNoise = app.CallingApp.NewContactPoint.MinNoise;
            MaxNoise = app.CallingApp.NewContactPoint.MaxNoise;
            i = min(app.Height) + MinNoise*(max(app.Height)-min(app.Height));
            f = min(app.Height) + MaxNoise*(max(app.Height)-min(app.Height));
            patch(app.UIAxes,[i f f i],...
                [min(app.Force) min(app.Force) max(app.Force) max(app.Force)],...
                'blue','FaceAlpha',0.2,'EdgeColor','none')
            hold(app.UIAxes,'off')
        end
        
        function deleteapp(app)
            delete(app)
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, row, col)
            app.DialogApp.CloseRequestFcn = '';
            
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            app.row = row;
            app.col = col;
            % Update UI with input values
            app.updateaxes(row,col); 
        end

        % Button pushed function: DeletedatapointButton
        function DeletedatapointButtonPushed(app, event)
            answer = questdlg('Are you sure?', ...
                    'Delete variable', ...
                    'Yes','No','No');
                switch answer
                    case 'Yes'
                        app.CallingApp.deletePoint
                    case 'No'
                end
        end

        % Button pushed function: ExportFigureButton
        function ExportFigureButtonPushed(app, event)
            fig = figure('Visible','on');
            app.updateaxes(app.row,app.col,fig);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DialogApp = uifigure('Visible', 'off');
            app.DialogApp.Name = 'Force curve';
            app.UIAxes = uiaxes(app.DialogApp);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DeletedatapointButton = uibutton(app.DialogApp, 'push');
            app.DeletedatapointButton.ButtonPushedFcn = createCallbackFcn(app, @DeletedatapointButtonPushed, true);
            app.DeletedatapointButton.Text = 'Delete data point';
            app.ExportFigureButton = uibutton(app.DialogApp, 'push');
            app.ExportFigureButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFigureButtonPushed, true);
            app.ExportFigureButton.Text = 'Export Figure';
            % Show the figure after all components are created
            app.DialogApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ForceCurveApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DialogApp)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DialogApp)
        end
    end
end