classdef HeightProfile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DialogApp                  matlab.ui.Figure
        ExportFigureButton         matlab.ui.control.Button
        LineThicknessSpinner       matlab.ui.control.Spinner
        LineThicknessSpinnerLabel  matlab.ui.control.Label
        UIAxesCol                  matlab.ui.control.UIAxes
        UIAxesRow                  matlab.ui.control.UIAxes
    end


    properties (Access = private) 
        CallingApp   % Main app object
    end
    
    properties (Access = public)
        Height
        row
        col
        calledForceApp
        thickness
    end
    
    methods (Access = public)
      
        function updateaxes(app,row,col,fig)
            app.row = row;
            app.col = col;
            FilterName = app.CallingApp.DropDownFilter.Value;
            VarName = app.CallingApp.DropDownVariable.Value;
            CellName = app.CallingApp.DropDownCell.Value;
            field = app.CallingApp.Var.(FilterName).(VarName).(CellName);
            app.Height = field.data;
            rowIdx = max(1,floor(app.row-(app.thickness-1)/2)):min(floor(app.row+(app.thickness-1)/2),size(field.data,1));
            colIdx = max(1,floor(app.col-(app.thickness-1)/2)):min(floor(app.col+(app.thickness-1)/2),size(field.data,1));
            if nargin == 3
                plot(app.UIAxesRow,mean(app.Height(rowIdx,:),1),'.k')
                plot(app.UIAxesCol,mean(app.Height(:,colIdx),2),'.k')
                app.UIAxesRow.XLabel.String = 'Column index [pix]';
                app.UIAxesRow.YLabel.String = field.unit;
                app.UIAxesRow.Title.String = ['Row(s) = ' num2str(size(app.Height,1)-rowIdx(end)+1) ' to ' num2str(size(app.Height,1)-rowIdx(1)+1)];
                app.UIAxesCol.XLabel.String = 'Row index [pix]';
                app.UIAxesCol.YLabel.String = field.unit;
                app.UIAxesCol.Title.String = ['Col(s) = ' num2str(colIdx(1)) ' to ' num2str(colIdx(end))];
            elseif nargin == 4
                figure(fig)
                subplot(2,1,1)
                plot(mean(app.Height(rowIdx,:),1),'.k')
                xlabel('Column index [pix]');
                title(['Row(s) = ' num2str(size(app.Height,1)-rowIdx(end)+1) ' to ' num2str(size(app.Height,1)-rowIdx(1)+1)]);
                subplot(2,1,2)
                plot(mean(app.Height(:,colIdx),2),'.k')
                xlabel('Row index [pix]');
                title(['Col(s) = ' num2str(colIdx(1)) ' to ' num2str(colIdx(end))]);
            end
        end
        
        function deleteapp(app)
            delete(app)
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, row, col)
            app.DialogApp.CloseRequestFcn = '';
            
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            app.row = row;
            app.col = col;
            % Update UI with input values
            app.thickness = app.LineThicknessSpinner.Value;
            app.updateaxes(row,col); 
        end

        % Value changed function: LineThicknessSpinner
        function LineThicknessSpinnerValueChanged(app, event)
            app.thickness = app.LineThicknessSpinner.Value;
            app.updateaxes(app.row,app.col);
        end

        % Button pushed function: ExportFigureButton
        function ExportFigureButtonPushed(app, event)
            fig = figure('Visible','on');
            app.updateaxes(app.row,app.col,fig);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DialogApp = uifigure('Visible', 'off');
            app.DialogApp.Name = 'Height profile';
            app.UIAxesRow = uiaxes(app.DialogApp);
            xlabel(app.UIAxesRow, 'X')
            ylabel(app.UIAxesRow, 'Y')
            zlabel(app.UIAxesRow, 'Z')
            app.UIAxesCol = uiaxes(app.DialogApp);
            xlabel(app.UIAxesCol, 'X')
            ylabel(app.UIAxesCol, 'Y')
            zlabel(app.UIAxesCol, 'Z')
            app.LineThicknessSpinnerLabel = uilabel(app.DialogApp);
            app.LineThicknessSpinnerLabel.Text = 'Line Thickness';
            app.LineThicknessSpinner = uispinner(app.DialogApp);
            app.LineThicknessSpinner.Limits = [1 20];
            app.LineThicknessSpinner.RoundFractionalValues = 'on';
            app.LineThicknessSpinner.ValueChangedFcn = createCallbackFcn(app, @LineThicknessSpinnerValueChanged, true);
            app.LineThicknessSpinner.Value = 1;
            app.ExportFigureButton = uibutton(app.DialogApp, 'push');
            app.ExportFigureButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFigureButtonPushed, true);
            app.ExportFigureButton.Text = 'Export Figure';
            % Show the figure after all components are created
            app.DialogApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HeightProfile(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DialogApp)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DialogApp)
        end
    end
end