classdef PlotYfX < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DialogApp                matlab.ui.Figure
        ExporttoWorkspaceButton  matlab.ui.control.Button
        XDropDown                matlab.ui.control.DropDown
        XDropDownLabel           matlab.ui.control.Label
        YDropDown                matlab.ui.control.DropDown
        YDropDownLabel           matlab.ui.control.Label
        ExportFigureButton       matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
    end


    properties (Access = private) 
        CallingApp   % Main app object
    end
    
    properties (Access = public)
        X
        Y
    end
    
    methods (Access = public)
      
        function updateaxes(app,fig)
            app.XDropDown.Items = app.CallingApp.DropDownVariable.Items;
            app.YDropDown.Items = app.CallingApp.DropDownVariable.Items;
            FilterName = app.CallingApp.DropDownFilter.Value;
            CellName = app.CallingApp.DropDownCell.Value;
            VarXName = app.XDropDown.Value;
            VarYName = app.YDropDown.Value;
            fieldX = app.CallingApp.Var.(FilterName).(VarXName).(CellName);
            fieldY = app.CallingApp.Var.(FilterName).(VarYName).(CellName);
            app.X = fieldX.data;
            app.Y = fieldY.data;
            if nargin == 1
                plot(app.UIAxes,app.X,app.Y,'.k')
                app.UIAxes.XLabel.String = app.XDropDown.Value;
                app.UIAxes.YLabel.String = app.YDropDown.Value;
            elseif nargin == 2
                figure(fig)
                plot(app.X,app.Y,'.k')
                xlabel(app.XDropDown.Value)
                ylabel(app.YDropDown.Value)
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            % Update UI with input values
            app.updateaxes;

        end

        % Button pushed function: ExportFigureButton
        function ExportFigureButtonPushed(app, event)
            fig = figure('Visible','on');
            app.updateaxes(fig);
        end

        % Value changed function: XDropDown
        function XDropDownValueChanged(app, event)
            app.updateaxes;
        end

        % Value changed function: YDropDown
        function YDropDownValueChanged(app, event)
            app.updateaxes;
        end

        % Close request function: DialogApp
        function DialogAppCloseRequest(app, event)
            app.CallingApp.calledPlotYfXApp = [];
            delete(app)
        end

        % Button pushed function: ExporttoWorkspaceButton
        function ExporttoWorkspaceButtonPushed(app, event)
            assignin('base','X',app.X(~isnan(app.X)));
            assignin('base','Y',app.Y(~isnan(app.Y)));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DialogApp = uifigure('Visible', 'off');
            app.DialogApp.Name = 'Force curve';
            app.DialogApp.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.UIAxes = uiaxes(app.DialogApp);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ExportFigureButton = uibutton(app.DialogApp, 'push');
            app.ExportFigureButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFigureButtonPushed, true);
            app.ExportFigureButton.Text = 'Export Figure';
            app.YDropDownLabel = uilabel(app.DialogApp);
            app.YDropDownLabel.Text = 'Y';
            app.YDropDown = uidropdown(app.DialogApp);
            app.YDropDown.ValueChangedFcn = createCallbackFcn(app, @YDropDownValueChanged, true);
            app.XDropDownLabel = uilabel(app.DialogApp);
            app.XDropDownLabel.Text = 'X';
            app.XDropDown = uidropdown(app.DialogApp);
            app.XDropDown.ValueChangedFcn = createCallbackFcn(app, @XDropDownValueChanged, true);
            app.ExporttoWorkspaceButton = uibutton(app.DialogApp, 'push');
            app.ExporttoWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoWorkspaceButtonPushed, true);
            app.ExporttoWorkspaceButton.Text = 'Export to Workspace';
            % Show the figure after all components are created
            app.DialogApp.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlotYfX(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DialogApp)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DialogApp)
        end
    end
end