classdef FittingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        PresetmodelsDropDown         matlab.ui.control.DropDown
        PresetmodelsDropDownLabel    matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        vDropDown                    matlab.ui.control.DropDown
        vDropDownLabel               matlab.ui.control.Label
        PercentofpointstofitSpinner  matlab.ui.control.Spinner
        PercentofpointstofitSpinnerLabel  matlab.ui.control.Label
        RUNButton                    matlab.ui.control.Button
        ButtonPreviewFit             matlab.ui.control.Button
        UIVariableTable              matlab.ui.control.Table
        UIParameterTable             matlab.ui.control.Table
        SelectfitparameterssListBox  matlab.ui.control.ListBox
        SelectfitparameterssListBoxLabel  matlab.ui.control.Label
        FxEditField                  matlab.ui.control.EditField
        FxEditFieldLabel             matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        ButtonUpdateTables           matlab.ui.control.Button
    end


    properties (Access = private) 
        CallingApp   % Main app object
        CallingForceApp
        deflection
        force
        contactpoint
    end
    
    properties (Access = public)
        RunFitting = false;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, forceapp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            app.CallingForceApp = forceapp;

            VarName = app.CallingApp.DropDownVariable.Items;
            app.vDropDown.Items = VarName;
            app.vDropDown.Value = app.vDropDown.Items{end};
        end

        % Button pushed function: ButtonUpdateTables
        function ButtonUpdateTablesPushed(app, event)
            expression = app.FxEditField.Value;
            myfit = fittype(expression);
            variable = coeffnames(myfit);
            idx = find(ismember(variable, 'v'));
            if idx 
                app.SelectfitparameterssListBox.Items = variable(~ismember(variable, 'v'));
            else
                app.SelectfitparameterssListBox.Items = variable;
            end
            app.ButtonPreviewFit.Enable = 'on';
            app.SelectfitparameterssListBoxValueChanged
        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)
%             app.CallingApp.Fitting.Enable = false;
            % Delete the dialog box 
            delete(app)        
        end

        % Value changed function: SelectfitparameterssListBox
        function SelectfitparameterssListBoxValueChanged(app, event)
            list = app.SelectfitparameterssListBox.Items;
            valueVariable = app.SelectfitparameterssListBox.Value;
            tmp = ismember(list,valueVariable);
            app.UIVariableTable.Data = list(tmp)';
            if ~isempty(app.UIVariableTable.Data)
                app.UIVariableTable.Data(:,3) = {0};
                app.UIVariableTable.Data(:,4) = {-inf};
                app.UIVariableTable.Data(:,5) = {inf};
            end
            app.UIParameterTable.Data = list(~tmp)';
            if ~isempty(app.UIParameterTable.Data)
                app.UIParameterTable.Data(:,2) = {0};
            end
        end

        % Button pushed function: ButtonPreviewFit
        function ButtonPreviewFitPushed(app, event)
            expression = app.FxEditField.Value;            
            listCoefficients = app.UIVariableTable.Data(:,1)';
            listProblems = app.UIParameterTable.Data(:,1)';            
            StartPoint = cell2mat(app.UIVariableTable.Data(:,3)');
            LowerBound = cell2mat(app.UIVariableTable.Data(:,4)');
            UpperBound = cell2mat(app.UIVariableTable.Data(:,5)');
            Problem = app.UIParameterTable.Data(:,2)';
            myfit = fittype(expression);
            variable = coeffnames(myfit);
            idx = find(ismember(variable, 'v'));
            if idx 
                listProblems(end+1,1) = {'v'};
                
                [field,FilterName,~,CellName] = app.CallingApp.getValues;
                VarName = app.vDropDown.Value;
                data = field.data;
                y = size(data,2);
                col = floor(app.CallingApp.SliderCursorX.Value);
                ySlider = floor(app.CallingApp.SliderCursorY.Value);
                row = y - ySlider + 1;
                p = app.CallingApp.Var.(FilterName).(VarName).(CellName).data(row,col);
                Problem(end+1,1) = {p};
                app.CallingApp.Fitting.vName = VarName;
            else
                app.CallingApp.Fitting.vName = [];
            end
            myfit = getmyfit(expression,listCoefficients,listProblems);
            percent = app.PercentofpointstofitSpinner.Value;
            [fitted,row,col] = app.CallingApp.doForceFit(myfit,StartPoint,LowerBound,UpperBound,Problem,percent);
            app.CallingForceApp.updateaxes(row,col)
            
            c = coeffvalues(fitted);
            app.UIVariableTable.Data(:,6) = num2cell(c');
            err = confint(fitted);
            err = (err(2,:) - err(1,:))/2;
            app.UIVariableTable.Data(:,7) = num2cell(err');
            app.RUNButton.Enable = 'on';

            app.CallingApp.Fitting.myfit = myfit;
            app.CallingApp.Fitting.unitCoefficients = app.UIVariableTable.Data(:,2);
            app.CallingApp.Fitting.StartPoint = StartPoint;
            app.CallingApp.Fitting.LowerBound = LowerBound;
            app.CallingApp.Fitting.UpperBound = UpperBound;
            app.CallingApp.Fitting.Problem = Problem;
            app.CallingApp.Fitting.Percent = percent;
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            app.CallingApp.doBatchForceFit;
            DialogAppCloseRequest(app)
        end

        % Value changed function: PercentofpointstofitSpinner
        function PercentofpointstofitSpinnerValueChanged(app, event)
            app.ButtonPreviewFitPushed
        end

        % Value changed function: PresetmodelsDropDown
        function PresetmodelsDropDownValueChanged(app, event)
            value = app.PresetmodelsDropDown.Value;
            if strcmp(value,'Hertz (spherical)')
                app.FxEditField.Value = 'E/(1-nu^2)*4*sqrt(Rc)/3*x^(3/2)';
            elseif strcmp(value,'Hertz (conical)')
                app.FxEditField.Value = 'E/(1-nu^2)*2*tan(alpha)/pi*x^2';
            elseif strcmp(value,'Sneddon')
                app.FxEditField.Value = '0.8887*E/(1-nu^2)*tan(alpha)/pi*x^2';
            elseif strcmp(value,'Gavara Chadwick 2012')
                app.FxEditField.Value = '8*E*tan(theta)*x^2/(3*pi)*(1+1.7795*2*tan(theta)/pi^2*x/v)';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Fitting';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.ButtonUpdateTables = uibutton(app.UIFigure, 'push');
            app.ButtonUpdateTables.ButtonPushedFcn = createCallbackFcn(app, @ButtonUpdateTablesPushed, true);
            app.ButtonUpdateTables.Text = 'Update';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Enter the fitting model for the force F [in nN] as a function of x (here indentation [in um])';
            app.FxEditFieldLabel = uilabel(app.UIFigure);
            app.FxEditFieldLabel.Text = 'F(x) =';
            app.FxEditField = uieditfield(app.UIFigure, 'text');
            app.FxEditField.Value = '8*E*tan(theta)*x^2/(3*pi)*(1+1.7795*2*tan(theta)/pi^2*x/v)';
            app.SelectfitparameterssListBoxLabel = uilabel(app.UIFigure);
            app.SelectfitparameterssListBoxLabel.Text = 'Select fit parameters(s)';
            app.SelectfitparameterssListBox = uilistbox(app.UIFigure);
            app.SelectfitparameterssListBox.Items = {};
            app.SelectfitparameterssListBox.Multiselect = 'on';
            app.SelectfitparameterssListBox.ValueChangedFcn = createCallbackFcn(app, @SelectfitparameterssListBoxValueChanged, true);
            app.SelectfitparameterssListBox.Value = {};
            app.UIParameterTable = uitable(app.UIFigure);
            app.UIParameterTable.ColumnName = {'Parameter'; 'Value'};
            app.UIParameterTable.RowName = {};
            app.UIParameterTable.ColumnEditable = [false true];
            app.UIVariableTable = uitable(app.UIFigure);
            app.UIVariableTable.ColumnName = {'Variable'; 'Unit'; 'Start point'; 'Lower bound'; 'Upper bound'; 'Fitting'; 'Error (+/-)'};
            app.UIVariableTable.RowName = {};
            app.UIVariableTable.ColumnEditable = [false true true true true false false];
            app.ButtonPreviewFit = uibutton(app.UIFigure, 'push');
            app.ButtonPreviewFit.ButtonPushedFcn = createCallbackFcn(app, @ButtonPreviewFitPushed, true);
            app.ButtonPreviewFit.Enable = 'off';
            app.ButtonPreviewFit.Text = 'Preview fit';
            app.RUNButton = uibutton(app.UIFigure, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Enable = 'off';
            app.RUNButton.Text = 'RUN';
            app.PercentofpointstofitSpinnerLabel = uilabel(app.UIFigure);
            app.PercentofpointstofitSpinnerLabel.Text = 'Percent of points to fit';
            app.PercentofpointstofitSpinner = uispinner(app.UIFigure);
            app.PercentofpointstofitSpinner.Limits = [1 100];
            app.PercentofpointstofitSpinner.ValueChangedFcn = createCallbackFcn(app, @PercentofpointstofitSpinnerValueChanged, true);
            app.PercentofpointstofitSpinner.Value = 100;
            app.vDropDownLabel = uilabel(app.UIFigure);
            app.vDropDownLabel.Text = 'v = ';
            app.vDropDown = uidropdown(app.UIFigure);
            app.vDropDown.Items = {};
            app.vDropDown.Value = {};
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'You can pick one variable to add to your model. Should be called v.';
            app.PresetmodelsDropDownLabel = uilabel(app.UIFigure);
            app.PresetmodelsDropDownLabel.Text = 'Preset models:';
            app.PresetmodelsDropDown = uidropdown(app.UIFigure);
            app.PresetmodelsDropDown.Items = {'Hertz (spherical)', 'Hertz (conical)', 'Sneddon', 'Gavara Chadwick 2012'};
            app.PresetmodelsDropDown.ValueChangedFcn = createCallbackFcn(app, @PresetmodelsDropDownValueChanged, true);
            app.PresetmodelsDropDown.Value = 'Gavara Chadwick 2012';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FittingApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end